classdef ventana_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        LimpiarButton  matlab.ui.control.Button
        VolverButton   matlab.ui.control.Button
        Opcin7Button   matlab.ui.control.Button
        Opcin6Button   matlab.ui.control.Button
        Opcin5Button   matlab.ui.control.Button
        Opcin4Button   matlab.ui.control.Button
        Opcin3Button   matlab.ui.control.Button
        Opcin2Button   matlab.ui.control.Button
        Opcin1Button   matlab.ui.control.Button
        Prctica1Label  matlab.ui.control.Label
        UIAxes_5       matlab.ui.control.UIAxes
        UIAxes_4       matlab.ui.control.UIAxes
        UIAxes_3       matlab.ui.control.UIAxes
        UIAxes_2       matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Opcin1Button
        function Opcin1ButtonPushed(app, event)
             % Crear vector de tiempo
            t=linspace(0,2*pi,1000);
        
            %   Funciones a trabajar
            a_t=5*sin(2*t);
            b_t=3*cos(5*t);
            c_t=(a_t).*(b_t);
            e_t=(a_t)+(b_t);
            f_t=(a_t)-(b_t);

            plot(app.UIAxes, t, a_t);
            title(app.UIAxes,'Funcion 1 - 5sin(2t)');
            xlabel(app.UIAxes,'Tiempo');
            ylabel(app.UIAxes,'Amplitud');

             plot(app.UIAxes_2,t, b_t);
            title(app.UIAxes_2,'Funcion 2 - 3cos(5t)');
            xlabel(app.UIAxes_2,'Tiempo');
            ylabel(app.UIAxes_2,'Amplitud');

            plot(app.UIAxes_3,t, c_t);
            title(app.UIAxes_3,'Funcion 3 - a(t) * b(t)');
            xlabel(app.UIAxes_3,'Tiempo');
            ylabel(app.UIAxes_3,'Amplitud');

            plot(app.UIAxes_4,t, e_t);
            title(app.UIAxes_4,'Funcion 4 - a(t) + b(t)');
            xlabel(app.UIAxes_4,'Tiempo');
            ylabel(app.UIAxes_4,'Amplitud');

            plot(app.UIAxes_5,t, f_t);
            title(app.UIAxes_5,'Funcion 5 - a(t) - b(t)');
            xlabel(app.UIAxes_5,'Tiempo');
            ylabel(app.UIAxes_5,'Amplitud');
        end

        % Button pushed function: Opcin2Button
        function Opcin2ButtonPushed(app, event)
            % Crear vector de tiempo
            t=linspace(0,2*pi,1000);
        
            %   Funciones a trabajar
            a_t=5*sin(2*t);
            b_t=3*cos(5*t);
            e_t=(a_t)+(b_t);
            f_t=(a_t)-(b_t);

            plot(app.UIAxes,t, e_t);
            title(app.UIAxes,'Funcion - a(t) + b(t)');
            xlabel(app.UIAxes,'Tiempo');
            ylabel(app.UIAxes,'Amplitud');

            plot(app.UIAxes_2,t, f_t);
            title(app.UIAxes_2,'Funcion - a(t) - b(t)');
            xlabel(app.UIAxes_2,'Tiempo');
            ylabel(app.UIAxes_2,'Amplitud');
        end

        % Button pushed function: Opcin3Button
        function Opcin3ButtonPushed(app, event)
            % Crear vector de tiempo
            t=linspace(0,2*pi,1000);
        
            %Aumentar la amplitud de la funcion 1, 5 veces
            a_t=5*5*sin(2*t);
            b_t=3*cos(5*t);
            c_t=(a_t).*(b_t);
            e_t=(a_t)+(b_t);
            f_t=(a_t)-(b_t);

            plot(app.UIAxes,t, c_t);
            title(app.UIAxes,'Funcion : a(t) x b(t)');
            xlabel(app.UIAxes,'Tiempo');
            ylabel(app.UIAxes,'Amplitud');

            plot(app.UIAxes_2,t, e_t);
            title(app.UIAxes_2,'Funcion : a(t) + b(t)');
            xlabel(app.UIAxes_2,'Tiempo');
            ylabel(app.UIAxes_2,'Amplitud');

            plot(app.UIAxes_3,t, f_t);
            title(app.UIAxes_3,'Funcion : a(t) - b(t)');
            xlabel(app.UIAxes_3,'Tiempo');
            ylabel(app.UIAxes_3,'Amplitud');

        end

        % Button pushed function: Opcin4Button
        function Opcin4ButtonPushed(app, event)
             %Definiendo el intervalo del tiempo
            t_in = 0;
            t_fi = 1;

            %Intervalo de muestreo (1ms)
            int_m = 0.001;

            %Creando el nuevo vector de tiempo:
            t_x = t_in:int_m:t_fi;

            %Generando la nueva señal
            x_t=sin(40*t_x)+sin(80*t_x);

            plot(app.UIAxes,t_x, x_t);
            title(app.UIAxes,'Funcion 6 : sen(40t) + sen (80t)');
            xlabel(app.UIAxes,'Tiempo');
            ylabel(app.UIAxes,'Amplitud');
        end

        % Button pushed function: Opcin5Button
        function Opcin5ButtonPushed(app, event)
             % Definiendo el intervalo de tiempo
            t_in = 0;
            t_fi = 1;

            % Intervalo de muestreo (1 ms)
            int_1ms = 0.001;

            % Intervalo de muestreo (100 ms)
            int_100ms = 0.1;

            % Creando el nuevo vector de tiempo:
            t_1 = t_in:int_1ms:t_fi;
            t_100 = t_in:int_100ms:t_fi;

            % Generando las nuevas señales:
            sen_1ms = sin(40 * t_1) + sin(80 * t_1);
            sen_100 = sin(40 * t_100) + sin(80 * t_100);

            plot(app.UIAxes,t_1, sen_1ms);
            title(app.UIAxes,'T = 1ms : sen(40t) + sen (80t)');
            xlabel(app.UIAxes,'Tiempo');
            ylabel(app.UIAxes,'Amplitud');

            plot(app.UIAxes_2,t_100, sen_100);
            title(app.UIAxes_2,'T = 100ms : sen(40t) + sen (80t)');
            xlabel(app.UIAxes_2,'Tiempo');
            ylabel(app.UIAxes_2,'Amplitud');
        end

        % Button pushed function: Opcin6Button
        function Opcin6ButtonPushed(app, event)
            frecuencia = 2;

            % Definiendo el intervalo de tiempo
            t_in = 0;
            t_fi = 1;

            % Intervalo de muestreo (1 ms)
            int_1ms = 0.001;

            % Intervalo de muestreo (100 ms)
            int_100ms = 0.1;

            % Creando el nuevo vector de tiempo:
            t_1 = t_in:int_1ms:t_fi;
            t_100 = t_in:int_100ms:t_fi;

            % Generando las nuevas señales:
            sen_1ms = sin((40 * t_1)*frecuencia) + sin((80 * t_1)*frecuencia);
            sen_100 = sin((40 * t_100)*frecuencia) + sin((80 * t_100)*frecuencia);

            plot(app.UIAxes,t_1, sen_1ms);
            title(app.UIAxes,'T = 1ms : sen(40t) + sen (80t)');
            xlabel(app.UIAxes,'Tiempo');
            ylabel(app.UIAxes,'Amplitud');

            plot(app.UIAxes_2,t_100, sen_100);
            title(app.UIAxes_2,'T = 100ms : sen(40t) + sen (80t)');
            xlabel(app.UIAxes_2,'Tiempo');
            ylabel(app.UIAxes_2,'Amplitud');
        end

        % Button pushed function: Opcin7Button
        function Opcin7ButtonPushed(app, event)
            frecuencia = 10;

            % Definiendo el intervalo de tiempo
            t_in = 0;
            t_fi = 1;

            % Intervalo de muestreo (1 ms)
            int_1ms = 0.001;

            % Intervalo de muestreo (100 ms)
            int_100ms = 0.1;

            % Creando el nuevo vector de tiempo:
            t_1 = t_in:int_1ms:t_fi;
            t_100 = t_in:int_100ms:t_fi;

            % Generando las nuevas señales:
            sen_1ms = sin((40 * t_1)*frecuencia) + sin((80 * t_1)*frecuencia);
            sen_100 = sin((40 * t_100)*frecuencia) + sin((80 * t_100)*frecuencia);

            plot(app.UIAxes,t_1, sen_1ms);
            title(app.UIAxes,'T = 1ms : sen(40t) + sen (80t)');
            xlabel(app.UIAxes,'Tiempo');
            ylabel(app.UIAxes,'Amplitud');

            plot(app.UIAxes_2,t_100, sen_100);
            title(app.UIAxes_2,'T = 100ms : sen(40t) + sen (80t)');
            xlabel(app.UIAxes_2,'Tiempo');
            ylabel(app.UIAxes_2,'Amplitud');
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            Prac__1;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            % Limpia todas las UIAxes
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);

            % Borra los títulos y etiquetas de ejes
            app.UIAxes.Title.String = '';
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
        
            app.UIAxes_2.Title.String = '';
            app.UIAxes_2.XLabel.String = '';
            app.UIAxes_2.YLabel.String = '';
        
            app.UIAxes_3.Title.String = '';
            app.UIAxes_3.XLabel.String = '';
            app.UIAxes_3.YLabel.String = '';
        
            app.UIAxes_4.Title.String = '';
            app.UIAxes_4.XLabel.String = '';
            app.UIAxes_4.YLabel.String = '';
        
            app.UIAxes_5.Title.String = '';
            app.UIAxes_5.XLabel.String = '';
            app.UIAxes_5.YLabel.String = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.Prctica1Label = uilabel(app.UIFigure);
            app.Prctica1Label.Text = 'Práctica 1';
            app.Opcin1Button = uibutton(app.UIFigure, 'push');
            app.Opcin1Button.ButtonPushedFcn = createCallbackFcn(app, @Opcin1ButtonPushed, true);
            app.Opcin1Button.Text = 'Opción 1';
            app.Opcin2Button = uibutton(app.UIFigure, 'push');
            app.Opcin2Button.ButtonPushedFcn = createCallbackFcn(app, @Opcin2ButtonPushed, true);
            app.Opcin2Button.Text = 'Opción 2';
            app.Opcin3Button = uibutton(app.UIFigure, 'push');
            app.Opcin3Button.ButtonPushedFcn = createCallbackFcn(app, @Opcin3ButtonPushed, true);
            app.Opcin3Button.Text = 'Opción 3';
            app.Opcin4Button = uibutton(app.UIFigure, 'push');
            app.Opcin4Button.ButtonPushedFcn = createCallbackFcn(app, @Opcin4ButtonPushed, true);
            app.Opcin4Button.Text = 'Opción 4';
            app.Opcin5Button = uibutton(app.UIFigure, 'push');
            app.Opcin5Button.ButtonPushedFcn = createCallbackFcn(app, @Opcin5ButtonPushed, true);
            app.Opcin5Button.Text = 'Opción 5';
            app.Opcin6Button = uibutton(app.UIFigure, 'push');
            app.Opcin6Button.ButtonPushedFcn = createCallbackFcn(app, @Opcin6ButtonPushed, true);
            app.Opcin6Button.Text = 'Opción 6';
            app.Opcin7Button = uibutton(app.UIFigure, 'push');
            app.Opcin7Button.ButtonPushedFcn = createCallbackFcn(app, @Opcin7ButtonPushed, true);
            app.Opcin7Button.Text = 'Opción 7';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ventana_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end