classdef PRAC_CES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SealLabel        matlab.ui.control.Label
        tabla            matlab.ui.control.Table
        EjeYSlider       matlab.ui.control.Slider
        EjeYSliderLabel  matlab.ui.control.Label
        EjeXSlider       matlab.ui.control.Slider
        EjeXSliderLabel  matlab.ui.control.Label
        Funcin10Button   matlab.ui.control.Button
        Funcin9Button    matlab.ui.control.Button
        Funcin8Button    matlab.ui.control.Button
        Funcin7Button    matlab.ui.control.Button
        Funcin6Button    matlab.ui.control.Button
        Funcin5Button    matlab.ui.control.Button
        Funcin4Button    matlab.ui.control.Button
        Funcin3Button    matlab.ui.control.Button
        Funcin2Button    matlab.ui.control.Button
        Funcin1Button    matlab.ui.control.Button
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Funcin1Button
        function Funcin1ButtonPushed(app, event)
            %Posicion de un objeto que esta en caída libre
            %El comportamiento del fenomeno esta dado por:
            %   h= h0 - 1/2 g * t al cuadrado

            % Parámetros
            h0 = app.EjeYSlider.Value; % Altura inicial en metros
            t = app.EjeXSlider.Value; % Tiempo en segundos
            g = 9.81; % Aceleración debida a la gravedad en m/s^2

            % Vector de tiempo
            t_vec = 0:0.1:t;

            % Calcula la posición en función del tiempo
            h = h0 - 0.5 * g * t_vec.^2;

            % Llego al suelo
            h(h < 0) = 0;

            % Actualiza la gráfica
            plot(app.UIAxes, t_vec, h);
            title(app.UIAxes, 'Posición de un objeto en caída Libre');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Altura (m)');

            t = {'Posicion de un objeto en caida libre', 'una variable', 'escalar', 'continua', 'continuo', 'determinista'};
            app.tabla.Data = t;

            texto = 'La variable independiente puede tomar un valor continuo, ya que puede variar y no se limita a valores específicos.';

            app.SealLabel.Text = texto;
        end

        % Button pushed function: Funcin2Button
        function Funcin2ButtonPushed(app, event)
            % Parámetros
            A = app.EjeYSlider.Value; % Amplitud de la señal
            f = app.EjeXSlider.Value; % Frecuencia de la señal
            t = linspace(0, 2, 1000); % Vector de tiempo de 0 a 2 segundos

            % Calcula la señal cuadrada
            pift = 2 * pi * f * t; % Fase de la señal
            senal_cuadrada = A * sign(sin(pift));

            % Aplica la restricción en el rango del eje y
            senal_cuadrada(senal_cuadrada > A) = A;
            senal_cuadrada(senal_cuadrada < 0) = 0;

            % Grafica la señal cuadrada
            plot(app.UIAxes2, t, senal_cuadrada);
            title(app.UIAxes2, 'Señal Cuadrada');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Amplitud');

            t = {'Ciclos de reloj para el funcionamiento del pipeline de un procesador', 'una variable (t)', 'escalar', 'discreta o continua', 'discreto', 'determinista'};
            app.tabla.Data = t;

            texto = 'Las variables de las que depende puede ser continua o discreta según se analice, y es escalar ya que solo tendra un valor en un instante de tiempo';

            app.SealLabel.Text = texto;
        end

        % Button pushed function: Funcin3Button
        function Funcin3ButtonPushed(app, event)
            % Parámetros
            intensidad = app.EjeYSlider.Value; % Intensidad del sonido (mayor intensidad -> mayor amplitud)
            f = app.EjeXSlider.Value; % Frecuencia de la señal

            % Rango de tiempo
            t = linspace(0, 1, 1000);

            % Calcula la señal senoidal con amplitud proporcional a la intensidad
            amplitud = intensidad / 100; % Escala la intensidad a un rango adecuado para la amplitud
            senal_senoidal = amplitud * sin(2 * pi * f * t);

            % Grafica la señal senoidal
            plot(app.UIAxes, t, senal_senoidal);
            title(app.UIAxes, 'Señal Senoidal de Intensidad de Sonido');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');

            t = {'Intensidad del sonido, por una señal senoidal', 'una variable (t)', 'escalar', 'continua', 'continuos', 'determinista'};
            app.tabla.Data = t;

            texto = 'Depende de una sola variable y puede variar con respecto al tiempo, por lo que puede cambiar su intensidad';

            app.SealLabel.Text = texto;
        end

        % Button pushed function: Funcin4Button
        function Funcin4ButtonPushed(app, event)
            % Parámetros
            intensidad = app.EjeYSlider.Value; % Intensidad de la fuerza (mayor intensidad -> mayor amplitud)
            f_fuerza = app.EjeXSlider.Value; % Frecuencia de la fuerza
            f_area = 2 * f_fuerza; % Frecuencia del cambio de área (puedes ajustarlo según tus necesidades)
            t = linspace(0, 1, 1000); % Rango de tiempo

            % Calcula la señal de fuerza en función del tiempo (por ejemplo, una señal senoidal)
            amplitud_fuerza = intensidad / 100; % Escala la intensidad a un rango adecuado para la amplitud de la fuerza
            fuerza = amplitud_fuerza * sin(2 * pi * f_fuerza * t);

            % Calcula la señal de área en función del tiempo (por ejemplo, una señal cuadrada)
            amplitud_area = 1; % Amplitud del cambio de área
            area = amplitud_area * square(2 * pi * f_area * t);

            % Calcula la señal de presión como la fuerza dividida por el área
            presion = fuerza ./ area;

            % Grafica la señal de presión
            plot(app.UIAxes2, t, presion);
            title(app.UIAxes2, 'Señal de Presión');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Presión');

            t = {'Presión de un objeto', 'Múltiples ( fuerza y área )', 'escalar', 'continua', 'continuos', 'determinista'};
            app.tabla.Data = t;

            texto = 'Tiene dimensión escalar ya que es resultante a dos variables, y esto hace que sus variables puedan cambiar y su valor puede cambiar';

            app.SealLabel.Text = texto;


        end

        % Button pushed function: Funcin5Button
        function Funcin5ButtonPushed(app, event)
            % Parámetros
            intensidad = app.EjeYSlider.Value; % Intensidad de la fuerza (mayor intensidad -> mayor amplitud)
            f_fuerza = app.EjeXSlider.Value; % Frecuencia de la fuerza
            f_area = 2 * f_fuerza; % Frecuencia del cambio de área (puedes ajustarlo según tus necesidades)
            t = linspace(0, 1, 1000); % Rango de tiempo

            % Inicializa la señal de energía cinética como cero
            energia_cinetica = zeros(size(t));

            % Calcula la energía cinética en función del tiempo
            for i = 1:length(t)
                % Utiliza intensidad y frecuencia de la fuerza para calcular la energía cinética
                amplitud_fuerza = intensidad / 100; % Escala la intensidad a un rango adecuado para la amplitud de la fuerza
                senal_fuerza = amplitud_fuerza * sin(2 * pi * f_fuerza * t(i));

                % Supongamos que la velocidad está relacionada con la amplitud de la fuerza
                velocidad = senal_fuerza;

                % Supongamos una masa constante
                masa = 1.0; % Puedes ajustar la masa según tus necesidades

                % Calcula la energía cinética en este instante de tiempo
                energia_cinetica(i) = 0.5 * masa * velocidad^2;
            end

            % Grafica la señal de energía cinética
            plot(app.UIAxes, t, energia_cinetica);
            title(app.UIAxes, 'Energía Cinética en Función del Tiempo');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Energía Cinética (Joules)');

            t = {'Energía cinética en un instante del tiempo', 'Múltiples ( masa y velocidad )', 'escalar', 'continuos', 'continuos', 'determinista'};
            app.tabla.Data = t;

            texto = 'Debido a que hay una ecuación no es posible que hayan componentes aleatorios para su cálculo';

            app.SealLabel.Text = texto;

        end

        % Button pushed function: Funcin6Button
        function Funcin6ButtonPushed(app, event)
            %Registro de aves

            num_aves = 20; % Número de aves
            A = app.EjeXSlider.Value;
            B = app.EjeYSlider.Value;
            x_aves = rand(1, num_aves) * A; % Coordenadas X aleatorias
            y_aves = rand(1, num_aves) * B; % Coordenadas Y aleatorias
            scatter(app.UIAxes2,x_aves, y_aves);

            title(app.UIAxes2,'Registro de aves volando');
            xlabel(app.UIAxes2,'Coordenada X');
            ylabel(app.UIAxes2,'Coordenada Y');

            t = {'Registro de aves en una posición', 'Múltiples ( posición X o Y )', 'vectorial', 'continuo', 'discreto', 'determinista o aleatoria'};
            app.tabla.Data = t;

            texto = 'Los valores que podemos tomar en la hora del registro pueden ser continuos, ya que pueden tomar varios valores dentro del espacio y a la hora de representarlos en el plano esta dado en un punto en el plano X, Y';

            app.SealLabel.Text = texto;
        end

        % Button pushed function: Funcin7Button
        function Funcin7ButtonPushed(app, event)

            % Obtener los valores de los sliders
            frecuencia_cardiaca = app.EjeXSlider.Value; % Frecuencia cardíaca en latidos por minuto
            amplitud_P = app.EjeYSlider.Value; % Amplitud de la onda P
            amplitud_QRS = 0.6; % Amplitud del complejo QRS
            amplitud_T = 0.5; % Amplitud de la onda T

            % Definir el tiempo de simulación y la frecuencia de muestreo
            tiempo = 0:0.001:10; % Tiempo de 0 a 10 segundos con paso de 1 ms

            % Simular la señal ECG con los valores de los sliders
            y = amplitud_P * sin(2 * pi * frecuencia_cardiaca * tiempo / 60) + ...
                amplitud_QRS * randn(size(tiempo)) + ...
                amplitud_T * sin(2 * pi * 0.5 * frecuencia_cardiaca * tiempo / 60);

            % Graficar la señal en el UIAxes de tu aplicación
            plot(app.UIAxes, tiempo, y);
            title(app.UIAxes, 'Señal Electrocardiograma');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud de ECG');

            t = {'Electrocardiograma', 'Una variable', 'escalar', 'continua', 'continua', 'determinista o aleatoria'};
            app.tabla.Data = t;

            texto = 'Las variables de las que depende el electro son continuas, ya que la señal se registra y se representa en función del tiempo y debido a su naturaleza que fluye de manera continua y no en unidades discreta';

            app.SealLabel.Text = texto;

        end

        % Button pushed function: Funcin8Button
        function Funcin8ButtonPushed(app, event)
            %Funcion exponencial que representa la deformación unitaria en
            %de un material en funcion del tiempo.
            %df = A * exp( - k * t)
            % k = Constante de relación
            A = app.EjeYSlider.Value;
            T = app.EjeXSlider.Value;
            K = log(A) / T;

            % Vector de tiempo
            t_vec = 0:0.1:T;

            % Calcula la deformación en función del tiempo
            deformacion = A * exp(-K * t_vec);

            % Actualiza la gráfica
            plot(app.UIAxes2, t_vec, deformacion);
            title(app.UIAxes2, 'Deformación Unitaria de un material');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Deformación');

            t = {'Deformación Unitaria', 'Una variable', 'escalar', 'continua', 'continua', 'determinista'};
            app.tabla.Data = t;

            texto = 'La señal de deformación unitaria se representa en función del tiempo mediante una función exponencial.  La naturaleza de esta señal es determinista, ya que se calcula de manera determinista utilizando la función exponencial.';
            app.SealLabel.Text = texto;


        end

        % Button pushed function: Funcin9Button
        function Funcin9ButtonPushed(app, event)
            %% Obtener los valores de los sliders
            A = app.EjeYSlider.Value;  % Amplitud de la señal senoidal original
            T = app.EjeXSlider.Value;  % Duración de la señal senoidal original
            f_moduladora = 1;          % Frecuencia de la señal moduladora
            fs = 1000;                 % Frecuencia de muestreo

            % Vector de tiempo
            t_vec = 0:1/fs:T;

            % Señal senoidal original
            senal_seno_original = A * sin(2 * pi * t_vec / T);

            % Señal moduladora
            senal_moduladora = 10 * sin(2 * pi * f_moduladora * t_vec);

            % Parámetros de la modulación de frecuencia
            kf = 10;  % Índice de modulación de frecuencia

            % Señal modulada en frecuencia
            senal_fm = A * sin(2 * pi * t_vec / T + kf * cumsum(senal_moduladora) * 1/fs);

            % Graficar la señal senoidal original, la moduladora y la modulada en frecuencia
            plot(app.UIAxes, t_vec, senal_seno_original, 'b', t_vec, senal_fm, 'r');
            title(app.UIAxes, 'Señal FM Modulada');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            legend(app.UIAxes, 'Señal Original','Señal FM Modulada');


            t = {'Señal de Frecuencia Modulada', 'Múltiples', 'escalar', 'continua', 'continua', 'determinista'};
            app.tabla.Data = t;

            texto = 'La señal de FM puede tener una naturaleza determinista, ya que su forma y frecuencia están determinadas por las ecuaciones utilizadas para su generación.';
            app.SealLabel.Text = texto;


        end

        % Button pushed function: Funcin10Button
        function Funcin10ButtonPushed(app, event)
            % Obtener los valores de los sliders
            A = app.EjeYSlider.Value;  % Amplitud de la señal senoidal original
            T = app.EjeXSlider.Value;  % Duración de la señal senoidal original
            f_moduladora = 1;          % Frecuencia de la señal moduladora
            fs = 1000;                 % Frecuencia de muestreo

            % Vector de tiempo
            t_vec = 0:1/fs:T;

            % Señal senoidal original
            senal_seno_original = A * sin(2 * pi * t_vec / T);

            % Señal moduladora
            senal_moduladora = 10 * sin(2 * pi * f_moduladora * t_vec);

            % Parámetros de la modulación de amplitud (AM)
            ka = 1;  % Índice de modulación de amplitud

            % Señal modulada en amplitud
            senal_am = (1 + ka * senal_moduladora) .* senal_seno_original;

            % Graficar la señal senoidal original, la moduladora y la modulada en amplitud
            plot(app.UIAxes2, t_vec, senal_seno_original, 'b', t_vec, senal_am, 'r');
            title(app.UIAxes2, 'Señal AM Modulada');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Amplitud');
            legend(app.UIAxes2, 'Señal Original', 'Señal AM Modulada');

            t = {'Señal de Modulación de Amplitud (AM)', 'Múltiples', 'escalar', 'continua', 'continua', 'determinista o aleatoria'};
            app.tabla.Data = t;

            texto = 'Puede depender de múltiples variables debido a la modulación de amplitud. La naturaleza de la señal de AM puede ser determinista o aleatoria, dependiendo de la señal moduladora y las condiciones de modulación. Puede volverse más aleatoria si se introduce ruido o componentes aleatorios durante la modulación.';
            app.SealLabel.Text = texto;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Funcin1Button = uibutton(app.UIFigure, 'push');
            app.Funcin1Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin1ButtonPushed, true);
            app.Funcin1Button.Text = 'Función 1';
            app.Funcin2Button = uibutton(app.UIFigure, 'push');
            app.Funcin2Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin2ButtonPushed, true);
            app.Funcin2Button.Text = 'Función 2';
            app.Funcin3Button = uibutton(app.UIFigure, 'push');
            app.Funcin3Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin3ButtonPushed, true);
            app.Funcin3Button.Text = 'Función 3';
            app.Funcin4Button = uibutton(app.UIFigure, 'push');
            app.Funcin4Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin4ButtonPushed, true);
            app.Funcin4Button.Text = 'Función 4';
            app.Funcin5Button = uibutton(app.UIFigure, 'push');
            app.Funcin5Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin5ButtonPushed, true);
            app.Funcin5Button.Text = 'Función 5';
            app.Funcin6Button = uibutton(app.UIFigure, 'push');
            app.Funcin6Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin6ButtonPushed, true);
            app.Funcin6Button.Text = 'Función  6';
            app.Funcin7Button = uibutton(app.UIFigure, 'push');
            app.Funcin7Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin7ButtonPushed, true);
            app.Funcin7Button.Text = 'Función 7';
            app.Funcin8Button = uibutton(app.UIFigure, 'push');
            app.Funcin8Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin8ButtonPushed, true);
            app.Funcin8Button.Text = 'Función 8';
            app.Funcin9Button = uibutton(app.UIFigure, 'push');
            app.Funcin9Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin9ButtonPushed, true);
            app.Funcin9Button.Text = 'Función 9';
            app.Funcin10Button = uibutton(app.UIFigure, 'push');
            app.Funcin10Button.ButtonPushedFcn = createCallbackFcn(app, @Funcin10ButtonPushed, true);
            app.Funcin10Button.Text = 'Función 10';
            app.EjeXSliderLabel = uilabel(app.UIFigure);
            app.EjeXSliderLabel.Text = 'Eje X';
            app.EjeXSlider = uislider(app.UIFigure);
            app.EjeXSlider.Limits = [0 10];
            app.EjeYSliderLabel = uilabel(app.UIFigure);
            app.EjeYSliderLabel.Text = 'Eje Y';
            app.EjeYSlider = uislider(app.UIFigure);
            app.EjeYSlider.Limits = [0 10];
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Señal'; 'No. de Variables'; 'Dimensionalidad'; 'Var. Indepen.'; 'Valores señal'; 'Naturaleza '};
            app.tabla.RowName = {};
            app.SealLabel = uilabel(app.UIFigure);
            app.SealLabel.Text = 'Señal:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRAC_CES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end