classdef PRACTICA3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        pares                        matlab.ui.control.ListBox
        periodica                    matlab.ui.control.ListBox
        PRACTICA3Label               matlab.ui.control.Label
        senalE                       matlab.ui.control.ListBox
        SealelementalListBoxLabel    matlab.ui.control.Label
        Tipo                         matlab.ui.control.ListBox
        TIPODESEALListBoxLabel       matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        senalE2                      matlab.ui.control.ListBox
        SealelementalListBoxLabel_2  matlab.ui.control.Label
        Operacion                    matlab.ui.control.ListBox
        OperacinListBoxLabel         matlab.ui.control.Label
        PeriodosagraficarSlider      matlab.ui.control.Spinner
        PeriodosagraficarLabel       matlab.ui.control.Label
        iniciarActualizarButton      matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        FrecuenciaMuestreo           matlab.ui.control.NumericEditField
        FrecuenciaMuestreoLabel      matlab.ui.control.Label
        FrecuenciaFundamental        matlab.ui.control.NumericEditField
        FrecuenciaFundamentalEditFieldLabel  matlab.ui.control.Label
        DutyS                        matlab.ui.control.Slider
        CicloutilDutyLabel           matlab.ui.control.Label
        TiempoS                      matlab.ui.control.Slider
        TiempoLabel                  matlab.ui.control.Label
        AmplitudSlider               matlab.ui.control.Slider
        AmplitudSliderLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    methods (Access = public)


        function Aperiodicas(app)

            A = app.AmplitudSlider.Value; %Amplitud

            Fs = app.FrecuenciaMuestreo.Value; %Frecuencia de muestreo

            f = app.FrecuenciaFundamental.Value; %Frecuencia fundamental

            Num_Periodos = 1;

            T=Num_Periodos*(1/f); %numero de periodos a graficar;

            duty = app.DutyS.Value;

            phi = pi / 2; %desface en radianes

            tiempoSlider=app.TiempoS.Value;

            t = 0:1/Fs:T-1/Fs;

            n=0:T;

            unitstep = t>=0;

            if strcmp(app.Tipo.Value,'discreta')
                switch app.senalE.Value
                    case 'Senoidal'
                        n = 0:T;
                        if strcmp(app.pares.Value,'Par')
                            x = A*cos(2*pi*f*n-phi);
                        else
                            x = A*cos((2*pi*f*(-n)-phi));
                        end

                        stem(app.UIAxes, n,x);

                    case 'Exponencial'
                        alfa = -duty/100;
                        %n= 0:tiempoSlider;
                        if strcmp(app.pares.Value,'Par')
                            x1_single=A*(alfa).^n;
                        else
                            x1_single=A*(alfa).^-n;
                        end

                        stem(app.UIAxes,n,x1_single);

                    case 'Rampa'

                        app.UIAxes.YLim = [0,app.AmplitudSlider.Value+1];
                        n1 = 0:T/2; n2 = T/2:T;r = n1;
                        n = [n1,n2]; %Tiempo de Simulación


                        if strcmp(app.pares.Value,'Par')
                            x = A.*[zeros(1,length(n1)),r]; %Generación de Señal
                        else
                            x = A.*-[zeros(1,length(-n1)),r]; %Generación de Señal
                        end
                        stem(app.UIAxes,n,x);

                    case 'Cuadrada'
                        if strcmp(app.pares.Value,'Par')
                            x = A*square(2*pi*f*n,duty);
                        else
                            x = A*-square(2*pi*f*-n,duty);
                        end

                        stem(app.UIAxes, n,x)
                    case 'Triangular'

                        n= -tiempoSlider:f:tiempoSlider;
                        if strcmp(app.pares.Value,'Par')
                            x = tripuls(n,tiempoSlider);
                        else
                            x = -tripuls(-n,tiempoSlider);
                        end

                        stem(app.UIAxes,n,x);
                        app.UIAxes.XLim = [-app.TiempoS.Value,app.TiempoS.Value];
                    case 'Escalon Unitario'
                        unitstep = n>=0;
                        if strcmp(app.pares.Value,'Par')
                            x = A.*ones(1,length(n));
                        else

                            x = A.*-ones(1,length(-n));
                        end

                        stem(app.UIAxes, n,x);

                    case 'Impulso Unitario'
                        if strcmp(app.pares.Value,'Par')
                            x = A * (n==0);
                        else
                            x = A * (-n==0);
                        end

                        stem(app.UIAxes,n,x);

                    case 'Diente de Sierra'
                        if strcmp(app.pares.Value,'Par')
                            x = A*sawtooth(2*pi*f*n,duty/100);
                        else
                            x = A*sawtooth(2*pi*f*-n,duty/100);
                        end

                        stem(app.UIAxes, n,x)

                end

            else
                switch app.senalE.Value
                    case 'Senoidal'
                        %t = 0:1/Fs:T-1/Fs;
                        if strcmp(app.pares.Value,'Par')
                            x = A*sin(2*pi*f*t+phi)
                        else
                            x = A*-sin(2*pi*f*-t+phi)
                        end

                        plot(app.UIAxes, t,x)

                    case 'Exponencial'
                        t= -tiempoSlider:1/Fs:tiempoSlider;
                        if strcmp(app.pares.Value,'Par')
                            x=exp(t/2);
                        else
                            x=-exp(-t/2);
                        end
                        plot(app.UIAxes,t,x);


                    case 'Rampa'
                        app.UIAxes.YLim = [0,app.AmplitudSlider.Value+1];

                        t1 = 0:1/Fs:T/2; t2 = T/2:1/Fs:T;r = t1;

                        t = [t1,t2]; %Tiempo de Simulación


                        if strcmp(app.pares.Value,'Par')
                            x = A.*[zeros(1,length(t1)),r]; %Generación de Señal
                        else
                            x = A.*[zeros(1,length(-t1)),r]; %Generación de Señal
                        end

                        plot(app.UIAxes,t,x);

                    case 'Cuadrada'

                        if strcmp(app.pares.Value,'Par')
                            x = A*square(2*pi*f*t,duty);
                        else
                            x = A*-square(2*pi*f*-t,duty);
                        end
                        plot(app.UIAxes,t,x);

                    case 'Triangular'

                        t= -tiempoSlider:1/Fs:tiempoSlider;

                        if strcmp(app.pares.Value,'Par')
                            x = A*tripuls(t,tiempoSlider);
                        else
                            x = A*-tripuls(-t,tiempoSlider);
                        end
                        plot(app.UIAxes,t,x);
                        app.UIAxes.XLim = [-app.TiempoS.Value,app.TiempoS.Value];
                    case 'Escalon Unitario'

                        if strcmp(app.pares.Value,'Par')
                            unitstep = t>=0;
                            x = A*unitstep;
                        else
                            unitstep = -t>=0;
                            x = A*unitstep;
                        end
                        plot(app.UIAxes, t,x);

                    case 'Impulso Unitario'


                        if strcmp(app.pares.Value,'Par')
                            x = A * (t==0);
                        else
                            x = A * (-t==0);
                        end
                        plot(app.UIAxes,t,x);

                    case 'Diente de Sierra'
                        if strcmp(app.pares.Value,'Par')
                            x = A*sawtooth(2*pi*f*t,duty/100);
                        else
                            x = A*-sawtooth(2*pi*f*-t,duty/100);
                        end

                        plot(app.UIAxes,t,x)
                end
            end
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            xlabel(app.UIAxes,'Tiempo (s)');
            ylabel(app.UIAxes,'Amplitud');
        end


        function Graficar(app)
            A = app.AmplitudSlider.Value; %Amplitud

            Fs = app.FrecuenciaMuestreo.Value; %Frecuencia de muestreo

            f = app.FrecuenciaFundamental.Value; %Frecuencia fundamental

            Num_Periodos = app.PeriodosagraficarSlider.Value;

            T=Num_Periodos*(1/f); %numero de periodos a graficar;

            duty = app.DutyS.Value;

            phi = pi / 2; %desface en radianes

            tiempoSlider=app.TiempoS.Value;

            t = 0:1/Fs:T-1/Fs;

            n=0:T;

            unitstep = t>=0;

            if strcmp(app.Tipo.Value,'discreta')
                switch app.senalE.Value
                    case 'Senoidal'
                        n = 0:T;
                        if strcmp(app.pares.Value,'Par')
                            x = A*cos(2*pi*f*n-phi);
                        else
                            x = A*cos((2*pi*f*(-n)-phi));
                        end

                        stem(app.UIAxes, n,x);

                    case 'Exponencial'
                        alfa = -duty/100;
                        %n= 0:tiempoSlider;
                        if strcmp(app.pares.Value,'Par')
                            x1_single=A*(alfa).^n;
                        else
                            x1_single=A*(alfa).^-n;
                        end
                        % Inicializa la señal resultante
                        x1 = [];

                        % Repite la señal múltiples veces
                        for i = 1:Num_Periodos
                            x1 = [x1, x1_single];
                        end
                        stem(app.UIAxes,x1);

                    case 'Rampa'

                        app.UIAxes.YLim = [0,app.AmplitudSlider.Value+1];
                        n1 = 0:T/2; n2 = T/2:T;r = n1;
                        n = [n1,n2]; %Tiempo de Simulación


                        if strcmp(app.pares.Value,'Par')
                            x = A.*[zeros(1,length(n1)),r]; %Generación de Señal
                        else
                            x = A.*-[zeros(1,length(-n1)),r]; %Generación de Señal
                        end

                        % Inicializa la señal resultante
                        x1 = [];

                        % Repite la señal múltiples veces
                        for i = 1:Num_Periodos
                            x1 = [x1, x];
                        end

                        stem(app.UIAxes,x1);

                    case 'Cuadrada'
                        if strcmp(app.pares.Value,'Par')
                            x = A*square(2*pi*f*n,duty);
                        else
                            x = A*-square(2*pi*f*-n,duty);
                        end

                        stem(app.UIAxes, n,x)
                    case 'Triangular'

                        n= -T:T;
                        if strcmp(app.pares.Value,'Par')
                            x = tripuls(n,T);
                        else
                            x = -tripuls(-n,T);
                        end
                        % Inicializa la señal resultante
                        x1 = [];

                        % Repite la señal múltiples veces
                        for i = 1:Num_Periodos
                            x1 = [x1, x];
                        end
                        n_length = 2 * tiempoSlider / f + 1;
                        % Crea un nuevo vector de tiempo para la señal resultante
                        n_resultante = linspace(-tiempoSlider * Num_Periodos, tiempoSlider * Num_Periodos, length(x1));

                        stem(app.UIAxes,n_resultante, x1);
                        app.UIAxes.XLim = [-app.TiempoS.Value-.01,app.TiempoS.Value+.01];
                    case 'Escalon Unitario'
                        unitstep = n>=0;
                        if strcmp(app.pares.Value,'Par')
                            x = A.*ones(1,length(n));
                        else

                            x = A.*-ones(1,length(-n));
                        end

                        stem(app.UIAxes, n,x);

                    case 'Impulso Unitario'
                        if strcmp(app.pares.Value,'Par')
                            x = A * (n==0);
                        else
                            x = A * (-n==0);
                        end
                        % Inicializa la señal resultante
                        x1 = [];

                        % Repite la señal múltiples veces
                        for i = 1:Num_Periodos
                            x1 = [x1, x];
                        end

                        stem(app.UIAxes,x);

                    case 'Diente de Sierra'
                        if strcmp(app.pares.Value,'Par')
                            x = A*sawtooth(2*pi*f*n,duty/100);
                        else
                            x = A*sawtooth(2*pi*f*-n,duty/100);
                        end

                        stem(app.UIAxes, n,x)

                end


            else
                switch app.senalE.Value
                    case 'Senoidal'
                        %t = 0:1/Fs:T-1/Fs;
                        if strcmp(app.pares.Value,'Par')
                            x = A*sin(2*pi*f*t+phi)
                        else
                            x = A*-sin(2*pi*f*-t+phi)
                        end

                        plot(app.UIAxes, t,x)

                    case 'Exponencial'
                        t= -tiempoSlider:1/Fs:tiempoSlider;
                        if strcmp(app.pares.Value,'Par')
                            x=exp(t/2);
                        else
                            x=-exp(-t/2);
                        end
                        % Extiende la señal a lo largo de un rango más grande
                        t_extended = linspace(-tiempoSlider * Num_Periodos, tiempoSlider * Num_Periodos, length(t) * Num_Periodos);
                        x_extended = repmat(x, 1, Num_Periodos);

                        % Plotea la señal en tiempo continuo repetida
                        plot(app.UIAxes, t_extended, x_extended);


                    case 'Rampa'
                        app.UIAxes.YLim = [0,app.AmplitudSlider.Value+1];

                        t1 = 0:1/Fs:T/2; t2 = T/2:1/Fs:T;r = t1;

                        t = [t1,t2]; %Tiempo de Simulación


                        if strcmp(app.pares.Value,'Par')
                            x = A.*[zeros(1,length(t1)),r]; %Generación de Señal
                        else
                            x = A.*[zeros(1,length(-t1)),r]; %Generación de Señal
                        end

                        % Extiende la señal a lo largo de un rango más grande
                        t_extended = linspace(-tiempoSlider * Num_Periodos, tiempoSlider * Num_Periodos, length(t) * Num_Periodos);
                        x_extended = repmat(x, 1, Num_Periodos);

                        % Plotea la señal en tiempo continuo repetida
                        plot(app.UIAxes, t_extended, x_extended);

                    case 'Cuadrada'

                        if strcmp(app.pares.Value,'Par')
                            x = A*square(2*pi*f*t,duty);
                        else
                            x = A*-square(2*pi*f*-t,duty);
                        end
                        plot(app.UIAxes,t,x);

                    case 'Triangular'

                        t= -tiempoSlider:1/Fs:tiempoSlider;

                        if strcmp(app.pares.Value,'Par')
                            x = A*tripuls(t,tiempoSlider);
                        else
                            x = A*-tripuls(-t,tiempoSlider);
                        end
                        % Extiende la señal a lo largo de un rango más grande
                        t_extended = linspace(-tiempoSlider * Num_Periodos, tiempoSlider * Num_Periodos, length(t) * Num_Periodos);
                        x_extended = repmat(x, 1, Num_Periodos);

                        % Plotea la señal en tiempo continuo repetida
                        plot(app.UIAxes, t_extended, x_extended);
                        app.UIAxes.XLim = [-app.TiempoS.Value,app.TiempoS.Value];
                    case 'Escalon Unitario'

                        if strcmp(app.pares.Value,'Par')
                            unitstep = t>=0;
                            x = A*unitstep;
                        else
                            unitstep = -t>=0;
                            x = A*unitstep;
                        end
                        plot(app.UIAxes, t,x);

                    case 'Impulso Unitario'


                        if strcmp(app.pares.Value,'Par')
                            x = A * (t==0);
                        else
                            x = A * (-t==0);
                        end
                        plot(app.UIAxes,t,x);

                    case 'Diente de Sierra'
                        if strcmp(app.pares.Value,'Par')
                            x = A*sawtooth(2*pi*f*t,duty/100);
                        else
                            x = A*-sawtooth(2*pi*f*-t,duty/100);
                        end

                        plot(app.UIAxes,t,x)

                        
                end


            end
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            xlabel(app.UIAxes,'Tiempo (s)');
            ylabel(app.UIAxes,'Amplitud');

                    end

        function Operando_2 (app)

            A = app.AmplitudSlider.Value; %Amplitud

            Fs = app.FrecuenciaMuestreo.Value; %Frecuencia de muestreo

            f = app.FrecuenciaFundamental.Value; %Frecuencia fundamental

            Num_Periodos = 1;

            T=Num_Periodos*(1/f); %numero de periodos a graficar;

            duty = app.DutyS.Value;

            phi = pi / 2; %desface en radianes

            tiempoSlider=app.TiempoS.Value;

            t = 0:1/Fs:T-1/Fs;

            n=0:T;

            unitstep = t>=0;

            if strcmp(app.Tipo.Value,'discreta')
                switch app.senalE2.Value
                    case 'Senoidal'
                        n = 0:T;
                        if strcmp(app.pares.Value,'Par')
                            yt = A*cos(2*pi*f*n-phi);
                        else
                            yt = A*cos((2*pi*f*(-n)-phi));
                        end
                    case 'Diente de Sierra'

                        if strcmp(app.pares.Value,'Par')
                            yt = A*sawtooth(2*pi*f*n,duty/100);
                        else
                            yt = A*sawtooth(2*pi*f*-n,duty/100);
                        end

                    case 'Exponencial'
                        alfa = -duty/100;
                        %n = 0:T;
                        if strcmp(app.pares.Value,'Par')
                            yt_1 = A * (alfa).^n;
                        else
                            yt_1 = A * (alfa).^-n;
                        end

                        % Inicializa la señal resultante
                        yt = [];

                        % Repite la señal múltiples veces
                        for i = 1:Num_Periodos
                            yt = [yt, yt_1]; % Almacena la señal en yt_resultante
                        end

                    case 'Rampa'

                        app.UIAxes.YLim = [0,app.AmplitudSlider.Value+1];
                        n1 = 0:T/2; n2 = T/2:T;r = n1;
                        n = [n1,n2]; %Tiempo de Simulación

                        if strcmp(app.pares.Value,'Par')
                            yt_1 = A.*[zeros(1,length(n1)),r]; %Generación de Señal
                        else
                            yt_1 = A.*-[zeros(1,length(-n1)),r]; %Generación de Señal
                        end

                        % Inicializa la señal resultante
                        yt = [];

                        % Repite la señal múltiples veces
                        for i = 1:Num_Periodos
                            yt = [yt, yt_1];
                        end

                    case 'Cuadrada'

                        if strcmp(app.pares.Value,'Par')
                            yt = A*square(2*pi*f*n,duty);
                        else
                            yt = A*-square(2*pi*f*-n,duty);
                        end
                    case 'Triangular'

                        n= -T:T;
                        if strcmp(app.pares.Value,'Par')
                            yt_1 = tripuls(n,T);
                        else
                            yt_1 = -tripuls(-n,T);
                        end
                        % Inicializa la señal resultante
                        yt = [];

                        % Repite la señal múltiples veces
                        for i = 1:Num_Periodos
                            yt = [yt, yt_1];
                        end
                        n_length = 2 * tiempoSlider / f + 1;
                        % Crea un nuevo vector de tiempo para la señal resultante
                        n_resultante = linspace(-tiempoSlider * Num_Periodos, tiempoSlider * Num_Periodos, length(yt));
                    case 'Escalon Unitario'
                    case 'Impulso Unitario'
                end
            elseif strcmp(app.Tipo.Value,'continua')
                switch app.senalE2.Value
                    case 'Senoidal'
                        n = 0:T;
                        yt = A.*sin(2*pi*f.*t);
                    case 'Diente de Sierra'
                    case 'Exponencial'
                    case 'Rampa'
                    case 'Cuadrada'
                    case 'Triangular'
                    case 'Escalon Unitario'
                    case 'Impulso Unitario'    

                end

               app.senal_2 = yt;
            end

             
        end

        function Operador(app)

            if strcmp(app.Operacion.Value, 'Suma')
                sum = senal_1 + senal_2;

            elseif strcmp(app.Operacion.Value, 'Multiplicacion')
            elseif strcmp(app.Operacion.Value, 'Diferenciacion')
            elseif strcmp(app.Operacion.Value, 'Integracion')
            end

        end


        function limites(app)
            if strcmp(app.Tipo.Value,'discreta'  )
                tiempomin = 1;
                tiempomax = 24;
                app.TiempoS.Limits = [tiempomin, tiempomax];

            else
                tiempomin = 0.01;
                tiempomax = 0.1;
                app.TiempoS.Limits = [tiempomin, tiempomax];
                if strcmp(app.senalE.Value,'Rampa')
                    tiempomin = 5;
                    tiempomax = 15;
                    app.TiempoS.Limits = [tiempomin, tiempomax];
                end

            end

        end

        function auto(app)
            if strcmp(app.Tipo.Value,'discreta')
                app.TiempoS.Value = 24;
                app.FrecuenciaFundamental.Value = .125;
            else
                if strcmp(app.senalE.Value,'Rampa')
                    app.TiempoS.Value = 10;
                    app.FrecuenciaFundamental.Value = 1;
                else
                    app.TiempoS.Value = 0.03;
                    app.FrecuenciaFundamental.Value = 100;
                end


            end
            if strcmp(app.senalE.Value,'Triangular')
                app.TiempoS.Limits = [0, 5];
                app.TiempoS.Value = 2;
            elseif strcmp(app.senalE.Value,'Exponencial')
                app.TiempoS.Limits = [0.01, 30];
                app.TiempoS.Value = 10;
            elseif strcmp(app.senalE.Value,'Rampa')
                app.TiempoS.Limits = [5, 15];
                app.TiempoS.Value = 10;
            end
        end

        function centrar(app)

            % Establecer los nuevos límites centrados en el eje
            axis(app.UIAxes, 'auto');


        end

        function evento(app)
            limites(app);
            auto(app);

            if strcmp(app.periodica.Value,'Periodica')
                Graficar(app);
            else
                Aperiodicas(app);
            end
            centrar(app);

        end

        function evento2(app)
            %limites(app);

            if strcmp(app.periodica.Value,'Periodica')
                Graficar(app);
            else
                Aperiodicas(app);
            end
            centrar(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evento(app);
        end

        % Button pushed function: iniciarActualizarButton
        function iniciarActualizarButtonPushed(app, event)
            evento(app);
        end

        % Value changed function: senalE
        function senalEValueChanged(app, event)
            evento(app);

        end

        % Value changed function: Tipo
        function TipoValueChanged(app, event)
            evento(app);
        end

        % Value changed function: AmplitudSlider, DutyS, 
        % ...and 4 other components
        function AmplitudSliderValueChanged(app, event)
            evento2(app);
        end

        % Value changed function: pares
        function paresValueChanged(app, event)
            evento(app);

        end

        % Callback function
        function senalE_2ValueChanged(app, event)
            evento(app);
            
        end

        % Value changed function: Operacion
        function OperacionValueChanged(app, event)
            evento(app);
            
        end

        % Callback function
        function senalE_2Clicked(app, event)
            evento(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {515, 515, 515};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {515, 515};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TIPODESEALListBoxLabel = uilabel(app.LeftPanel);
            app.TIPODESEALListBoxLabel.Text = 'TIPO DE SEÑAL';
            app.Tipo = uilistbox(app.LeftPanel);
            app.Tipo.Items = {'discreta', 'continua'};
            app.Tipo.ValueChangedFcn = createCallbackFcn(app, @TipoValueChanged, true);
            app.Tipo.Value = 'discreta';
            app.SealelementalListBoxLabel = uilabel(app.LeftPanel);
            app.SealelementalListBoxLabel.Text = 'Señal elemental';
            app.senalE = uilistbox(app.LeftPanel);
            app.senalE.Items = {'Senoidal', 'Diente de Sierra', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalon Unitario', 'Impulso Unitario'};
            app.senalE.ValueChangedFcn = createCallbackFcn(app, @senalEValueChanged, true);
            app.senalE.Value = 'Senoidal';
            app.PRACTICA3Label = uilabel(app.LeftPanel);
            app.PRACTICA3Label.Text = 'PRACTICA 3';
            app.periodica = uilistbox(app.LeftPanel);
            app.periodica.Items = {'Periodica', 'Aperiodica'};
            app.periodica.Value = 'Periodica';
            app.pares = uilistbox(app.LeftPanel);
            app.pares.Items = {'Par', 'Impar'};
            app.pares.ValueChangedFcn = createCallbackFcn(app, @paresValueChanged, true);
            app.pares.Value = 'Par';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.iniciarActualizarButton = uibutton(app.CenterPanel, 'push');
            app.iniciarActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @iniciarActualizarButtonPushed, true);
            app.iniciarActualizarButton.Text = 'iniciar / Actualizar';
            app.PeriodosagraficarLabel = uilabel(app.CenterPanel);
            app.PeriodosagraficarLabel.Text = 'Periodos a graficar';
            app.PeriodosagraficarSlider = uispinner(app.CenterPanel);
            app.PeriodosagraficarSlider.Limits = [0 15];
            app.PeriodosagraficarSlider.ValueDisplayFormat = '%.0f';
            app.PeriodosagraficarSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudSliderValueChanged, true);
            app.PeriodosagraficarSlider.Value = 1;
            app.OperacinListBoxLabel = uilabel(app.CenterPanel);
            app.OperacinListBoxLabel.Text = 'Operación';
            app.Operacion = uilistbox(app.CenterPanel);
            app.Operacion.Items = {'Suma', 'Multiplicacion', 'Diferenciacion', 'Integracion'};
            app.Operacion.ValueChangedFcn = createCallbackFcn(app, @OperacionValueChanged, true);
            app.Operacion.Value = 'Suma';
            app.SealelementalListBoxLabel_2 = uilabel(app.CenterPanel);
            app.SealelementalListBoxLabel_2.Text = 'Señal elemental';
            app.senalE2 = uilistbox(app.CenterPanel);
            app.senalE2.Items = {'Senoidal', 'Diente de Sierra', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalon Unitario', 'Impulso Unitario'};
            app.senalE2.Value = 'Senoidal';
            app.RightPanel = uipanel(app.GridLayout);
            app.AmplitudSliderLabel = uilabel(app.RightPanel);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.AmplitudSlider = uislider(app.RightPanel);
            app.AmplitudSlider.Limits = [1 10];
            app.AmplitudSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.AmplitudSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudSliderValueChanged, true);
            app.AmplitudSlider.Value = 1;
            app.TiempoLabel = uilabel(app.RightPanel);
            app.TiempoLabel.Text = 'Tiempo';
            app.TiempoS = uislider(app.RightPanel);
            app.TiempoS.Limits = [0.01 1];
            app.TiempoS.ValueChangedFcn = createCallbackFcn(app, @AmplitudSliderValueChanged, true);
            app.TiempoS.Value = 0.03;
            app.CicloutilDutyLabel = uilabel(app.RightPanel);
            app.CicloutilDutyLabel.Text = 'Ciclo util (Duty)';
            app.DutyS = uislider(app.RightPanel);
            app.DutyS.ValueChangedFcn = createCallbackFcn(app, @AmplitudSliderValueChanged, true);
            app.DutyS.Value = 50;
            app.FrecuenciaFundamentalEditFieldLabel = uilabel(app.RightPanel);
            app.FrecuenciaFundamentalEditFieldLabel.Text = 'Frecuencia Fundamental';
            app.FrecuenciaFundamental = uieditfield(app.RightPanel, 'numeric');
            app.FrecuenciaFundamental.ValueChangedFcn = createCallbackFcn(app, @AmplitudSliderValueChanged, true);
            app.FrecuenciaFundamental.Value = 0.125;
            app.FrecuenciaMuestreoLabel = uilabel(app.RightPanel);
            app.FrecuenciaMuestreoLabel.Text = 'Frecuencia Muestreo';
            app.FrecuenciaMuestreo = uieditfield(app.RightPanel, 'numeric');
            app.FrecuenciaMuestreo.ValueDisplayFormat = '%.0f';
            app.FrecuenciaMuestreo.ValueChangedFcn = createCallbackFcn(app, @AmplitudSliderValueChanged, true);
            app.FrecuenciaMuestreo.Value = 10000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRACTICA3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end