classdef Prac4_czarin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Prctica3Label                 matlab.ui.control.Label
        SealListBox                   matlab.ui.control.ListBox
        SealListBoxLabel              matlab.ui.control.Label
        TipoListBox                   matlab.ui.control.ListBox
        TipoListBoxLabel              matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        OperacinListBox               matlab.ui.control.ListBox
        OperacinListBoxLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        FrecuenciamuestreoEditField   matlab.ui.control.NumericEditField
        FrecuenciamuestreoEditFieldLabel  matlab.ui.control.Label
        TiempoSlider                  matlab.ui.control.Slider
        TiempoSliderLabel             matlab.ui.control.Label
        CiclodetrabajoEditField       matlab.ui.control.NumericEditField
        CiclodetrabajoEditFieldLabel  matlab.ui.control.Label
        FrecuenciaSealEditField       matlab.ui.control.NumericEditField
        FrecuenciaSealEditFieldLabel  matlab.ui.control.Label
        AmplitudEditField             matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel        matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    methods (Access = public)


        function Graficar(app)

            % Obtener los valores ingresados en los campos de edición
            A = app.AmplitudEditField.Value;
            f = app.FrecuenciaFundamentalEditField.Value;

            Fs = app.FrecuenciamuestreoEditField.Value; %Calcular el muestro

            T = 3 * (1 / f); %Periodo para realizar un ciclo
            duty = app.CiclodetrabajoEditField.Value; %Ciclos de trabajo
            t = 0:1/Fs:T-1/Fs;


            %Para señales discretas
            n = 0:T;

            if strcmp(app.TipoListBox.Value , 'Discreta')
                switch app.SealListBox.Value
                    case 'Cuadrada'
                        x = A*square(2*pi*fr_sen*n,duty);
                        stem(app.UIAxes,t,x);
                    case 'Diente de sierra'
                    case 'Senoidal'
                    case 'Exponencial'
                    case 'Escalón'
                    case 'Impulso'
                    case 'Rampa'
                    case 'Rectangular'
                    case 'Triangular'

                end
            else
                if strcmp(app.TipoListBox.Value , 'Continua')
                    switch app.SealListBox.Value
                        case 'Cuadrada'
                            x = A*square(2*pi*f*t,ciclo_tr);
                            plot(app.UIAxes,t,x);
                        case 'Diente de sierra'
                        case 'Senoidal'
                        case 'Exponencial'
                        case 'Escalón'
                        case 'Impulso'
                        case 'Rampa'
                        case 'Rectangular'
                        case 'Triangular'

                    end
                end

            end
            grid on;
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            xlabel(app.UIAxes,'Tiempo (s)');
            ylabel(app.UIAxes,'Amplitud');
        end

        function evento(app)
            auto(app);
            Graficar(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 248};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TipoListBoxLabel = uilabel(app.LeftPanel);
            app.TipoListBoxLabel.Text = 'Tipo';
            app.TipoListBox = uilistbox(app.LeftPanel);
            app.TipoListBox.Items = {'Continua', 'Discreta'};
            app.TipoListBox.Value = 'Continua';
            app.SealListBoxLabel = uilabel(app.LeftPanel);
            app.SealListBoxLabel.Text = 'Señal';
            app.SealListBox = uilistbox(app.LeftPanel);
            app.SealListBox.Items = {'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial', 'Escalón', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.SealListBox.Value = 'Cuadrada';
            app.Prctica3Label = uilabel(app.LeftPanel);
            app.Prctica3Label.Text = 'Práctica 3';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OperacinListBoxLabel = uilabel(app.CenterPanel);
            app.OperacinListBoxLabel.Text = 'Operación';
            app.OperacinListBox = uilistbox(app.CenterPanel);
            app.OperacinListBox.Items = {'Desplazamiento', 'Escalamiento', 'Diferenciación', 'Integración', 'Escalamiento de tiempo', 'Reflexión'};
            app.OperacinListBox.Value = 'Desplazamiento';
            app.RightPanel = uipanel(app.GridLayout);
            app.AmplitudEditFieldLabel = uilabel(app.RightPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.RightPanel, 'numeric');
            app.FrecuenciaSealEditFieldLabel = uilabel(app.RightPanel);
            app.FrecuenciaSealEditFieldLabel.Text = 'Frecuencia Señal';
            app.FrecuenciaSealEditField = uieditfield(app.RightPanel, 'numeric');
            app.CiclodetrabajoEditFieldLabel = uilabel(app.RightPanel);
            app.CiclodetrabajoEditFieldLabel.Text = 'Ciclo de trabajo';
            app.CiclodetrabajoEditField = uieditfield(app.RightPanel, 'numeric');
            app.TiempoSliderLabel = uilabel(app.RightPanel);
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.RightPanel);
            app.TiempoSlider.Visible = 'off';
            app.FrecuenciamuestreoEditFieldLabel = uilabel(app.RightPanel);
            app.FrecuenciamuestreoEditFieldLabel.Text = 'Frecuencia muestreo';
            app.FrecuenciamuestreoEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prac4_czarin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end