classdef practica4_EquipoE1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        AlphaEditField                matlab.ui.control.NumericEditField
        AlphaEditFieldLabel           matlab.ui.control.Label
        ArgumentoButton               matlab.ui.control.Button
        MagnitudButton                matlab.ui.control.Button
        ImaginariaButton              matlab.ui.control.Button
        RealButton                    matlab.ui.control.Button
        PendienteEditField            matlab.ui.control.NumericEditField
        PendienteEditFieldLabel       matlab.ui.control.Label
        LinearectaEditField           matlab.ui.control.NumericEditField
        LinearectaEditFieldLabel      matlab.ui.control.Label
        TiempoMuestrasEditField       matlab.ui.control.NumericEditField
        TiempoMuestrasEditFieldLabel  matlab.ui.control.Label
        AnchoEditField                matlab.ui.control.NumericEditField
        AnchoEditFieldLabel           matlab.ui.control.Label
        PeriodosEditField             matlab.ui.control.NumericEditField
        PeriodosEditFieldLabel        matlab.ui.control.Label
        FrecuenciaEditField           matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel      matlab.ui.control.Label
        AmplitudEditField             matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel        matlab.ui.control.Label
        GENERADORDESEALESPanel        matlab.ui.container.Panel
        APERIDICACheckBox             matlab.ui.control.CheckBox
        PERIDICACheckBox              matlab.ui.control.CheckBox
        SEALDropDown                  matlab.ui.control.DropDown
        SEALDropDownLabel             matlab.ui.control.Label
        DISCRETACheckBox              matlab.ui.control.CheckBox
        CONTINUACheckBox              matlab.ui.control.CheckBox
        GRAFICARButton                matlab.ui.control.Button
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible =  "off";
            app.UIAxes2.Visible =  "off";
            app.UIAxes3.Visible =  "off";
            app.UIAxes4.Visible =  "off";
            app.UIAxes5.Visible =  "off";
            app.UIAxes6.Visible =  "off";
            app.RealButton.Visible = "off";
            app.ImaginariaButton.Visible = "off";
            app.MagnitudButton.Visible = "off";
            app.ArgumentoButton.Visible = "off";
            app.SEALDropDown.Enable = 'off';
        end

        % Value changed function: CONTINUACheckBox
        function CONTINUACheckBoxValueChanged(app, event)
            if app.CONTINUACheckBox.Value
                app.DISCRETACheckBox.Enable = 'off';
                app.PERIDICACheckBox.Enable = 'off';
                app.APERIDICACheckBox.Enable = 'off';
                app.SEALDropDown.Enable = 'on';
            else
                app.DISCRETACheckBox.Enable = 'on';
                app.PERIDICACheckBox.Enable = 'on';
                app.APERIDICACheckBox.Enable = 'on';
                app.SEALDropDown.Enable = 'off';
                cla(app.UIAxes);
            end
        end

        % Value changed function: DISCRETACheckBox
        function DISCRETACheckBoxValueChanged(app, event)
            if app.DISCRETACheckBox.Value
                app.CONTINUACheckBox.Enable = 'off';
                app.PERIDICACheckBox.Enable = 'off';
                app.APERIDICACheckBox.Enable = 'off';
                app.SEALDropDown.Enable = 'on';
            else
                app.CONTINUACheckBox.Enable = 'on';
                app.PERIDICACheckBox.Enable = 'on';
                app.APERIDICACheckBox.Enable = 'on';
                app.SEALDropDown.Enable = 'off';
                cla(app.UIAxes);
            end
        end

        % Button pushed function: GRAFICARButton
        function GRAFICARButtonPushed(app, event)
        signal = app.SEALDropDown.Value;

            if app.CONTINUACheckBox.Value == true
                switch signal
                    case 'Cuadrática'
                        % INICIALIZACIÓN
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        % GRÁFICA
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        frecuencia = app.FrecuenciaEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        t = linspace(-tiempo, tiempo, 1000);
                        x = amplitud*square(2*pi*frecuencia*t);
                        plot(app.UIAxes,t,x);
                        app.TextArea.Value='Es una señal de potencia';
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Diente de Sierra'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        frecuencia = app.FrecuenciaEditField.Value;
                        ancho = app.AnchoEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        t = linspace(-tiempo, tiempo, 1000);
                        x = amplitud*sawtooth(2*pi*frecuencia*t,ancho);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Senoidal'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        frecuencia = app.FrecuenciaEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        t = linspace(-tiempo, tiempo, 1000);
                        phi = pi/2;
                        x = amplitud*sin(2*pi*frecuencia*t+phi);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes, t, x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Exponenciales'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "on";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "on";
                        app.UIAxes6.Visible =  "on";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, 'Exponencial Creciente');
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'f(t)');
                        title(app.UIAxes2, 'Exponencial Decreciente');
                        xlabel(app.UIAxes2, 'Tiempo(s)');
                        ylabel(app.UIAxes2, 'f(t)');
                        app.TextArea.Value='Es una señal de Energia';
                        tiempo = app.TiempoMuestrasEditField.Value;
                        alpha = app.AlphaEditField.Value;
                        Fs = 10000;
                        t = 0:1/Fs:tiempo;
                        x1 = exp(t);
                        plot(app.UIAxes,t,x1);
                        x2=alpha.^(-t);
                        plot(app.UIAxes2,t,x2);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par Exp. Creciente');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x1_par = (x1 + fliplr(x1))/2;
                        plot(app.UIAxes3,t, x1_par);
                        title(app.UIAxes4, 'Componente impar Exp. Creciente');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x1_impar = (x1 - fliplr(x1))/2;
                        plot(app.UIAxes4,t, x1_impar);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes5, 'Componente par Exp. Decreciente');
                        xlabel(app.UIAxes5, 'Tiempo(s)');
                        ylabel(app.UIAxes5, 'Amplitud');
                        x2_par = (x2 + fliplr(x2))/2;
                        plot(app.UIAxes5,t, x2_par);
                        title(app.UIAxes6, 'Componente impar Exp. Decreciente');
                        xlabel(app.UIAxes6, 'Tiempo(s)');
                        ylabel(app.UIAxes6, 'Amplitud');
                        x2_impar = (x2 - fliplr(x2))/2;
                        plot(app.UIAxes6,t, x2_impar);
                    case  'Exponencial Compleja'
                        app.RealButtonPushed();
                        app.ImaginariaButtonPushed();
                        app.MagnitudButtonPushed();
                        app.ArgumentoButtonPushed();
                        app.TextArea.Value='Es una señal de Energia';
                    case 'Escalón'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        Fs = 10000;
                        t = -tiempo:1/Fs:tiempo;
                        app.TextArea.Value='Es una señal de potencia';
                        x = amplitud*heaviside(t);
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Impulso'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        app.TextArea.Value='Es una señal de Energia';
                        amplitud = app.AmplitudEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        t = -tiempo:1:tiempo;
                        x = amplitud*[zeros(1,tiempo),ones(1,1),zeros(1,tiempo)];
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Rampa'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        Fs = 10000;
                        t1 = 0:1/Fs:5;
                        t2 = 5:1/Fs:10;
                        r = t1;
                        t = [t1,t2];
                        x = amplitud.*[zeros(1,length(t1)),r];
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Rectangular'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);                        
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.TiempoMuestrasEditField.Value;
                        Fs = 10000;
                        t = -1:1/Fs:1;
                        x = rectpuls(t,amplitud);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Triangular'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.TiempoMuestrasEditField.Value;
                        Fs = 10000;
                        t = -2:1/Fs:2;
                        x = tripuls(t,amplitud);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                end
            elseif app.DISCRETACheckBox.Value == true
                switch signal
                    case 'Cuadrática'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        frecuencia = app.FrecuenciaEditField.Value;
                        n_muestras = app.TiempoMuestrasEditField.Value;
                        n = -n_muestras:n_muestras;
                        x = amplitud*square(2*pi*frecuencia*n);
                        app.TextArea.Value='Es una señal de potencia';
                        stem(app.UIAxes,n,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        stem(app.UIAxes3,n, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        stem(app.UIAxes4,n, x_impar);
                    case 'Diente de Sierra'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        frecuencia = app.FrecuenciaEditField.Value;
                        n_muestras = app.TiempoMuestrasEditField.Value;
                        ancho = app.AnchoEditField.Value;
                        n = -n_muestras:n_muestras;
                        x = amplitud*sawtooth(2*pi*frecuencia*n,ancho);
                        app.TextArea.Value='Es una señal de potencia';
                        stem(app.UIAxes,n,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        stem(app.UIAxes3,n, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        stem(app.UIAxes4,n, x_impar);
                    case 'Senoidal'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        frecuencia = app.FrecuenciaEditField.Value;
                        n_muestras = app.TiempoMuestrasEditField.Value;
                        phi = pi/2;
                        n = -n_muestras:n_muestras;
                        x = amplitud*cos(2*pi*frecuencia*n-phi);
                        app.TextArea.Value='Es una señal de potencia';
                        stem(app.UIAxes,n,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        stem(app.UIAxes3,n, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        stem(app.UIAxes4,n, x_impar);
                    case 'Exponenciales'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "on";
                        app.UIAxes6.Visible =  "on";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, 'Exponencial Creciente');
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'f(t)');
                        title(app.UIAxes2, 'Exponencial Decreciente');
                        xlabel(app.UIAxes2, 'Tiempo(s)');
                        ylabel(app.UIAxes2, 'f(t)');
                        app.TextArea.Value='Es una señal de Energia';
                        eje = app.TiempoMuestrasEditField.Value;
                        alpha = app.AlphaEditField.Value;
                        n = 0:eje;
                        x1 = exp(n);
                        stem(app.UIAxes,n,x1);
                        x2 = alpha.^(-n);
                        stem(app.UIAxes2,n,x2);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par Exp. Creciente');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x1_par = (x1 + fliplr(x1))/2;
                        stem(app.UIAxes3,n, x1_par);
                        title(app.UIAxes4, 'Componente impar Exp. Creciente');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x1_impar = (x1 - fliplr(x1))/2;
                        stem(app.UIAxes4,n, x1_impar);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes5, 'Componente par Exp. Decreciente');
                        xlabel(app.UIAxes5, 'Tiempo(s)');
                        ylabel(app.UIAxes5, 'Amplitud');
                        x2_par = (x2 + fliplr(x2))/2;
                        stem(app.UIAxes5,n, x2_par);
                        title(app.UIAxes6, 'Componente impar Exp. Decreciente');
                        xlabel(app.UIAxes6, 'Tiempo(s)');
                        ylabel(app.UIAxes6, 'Amplitud');
                        x2_impar = (x2 - fliplr(x2))/2;
                        stem(app.UIAxes6,n, x2_impar);
                    case 'Escalón'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        n_muestras = app.TiempoMuestrasEditField.Value;
                        n = -n_muestras:n_muestras;
                        x =amplitud*heaviside(n);
                        app.TextArea.Value='Es una señal de potencia';
                        stem(app.UIAxes,n,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        stem(app.UIAxes3,n, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        stem(app.UIAxes4,n, x_impar);
                    case 'Impulso'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        n_muestras = app.TiempoMuestrasEditField.Value;
                        n = -n_muestras:1:n_muestras;
                        x = amplitud*[zeros(1,n_muestras),ones(1,1),zeros(1,n_muestras)];
                        stem(app.UIAxes,n,x);
                        app.TextArea.Value='Es una señal de Energia';
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        stem(app.UIAxes3,n, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        stem(app.UIAxes4,n, x_impar);
                    case 'Rampa'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        app.TextArea.Value='Es una señal de Potencia';
                        amplitud = app.AmplitudEditField.Value;
                        n1=0:5;
                        n2=5:10;
                        r=n1;
                        n = [n1,n2];
                        x = amplitud.*[zeros(1,length(n1)),r];
                        stem(app.UIAxes,n,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        stem(app.UIAxes3,n, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        stem(app.UIAxes4,n, x_impar);
                    case 'Rectangular'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.TiempoMuestrasEditField.Value;
                        n=-1:0.1:1;
                        x = rectpuls(n,amplitud);
                        app.TextArea.Value='Es una señal de potencia';
                        stem(app.UIAxes,n,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        stem(app.UIAxes3,n, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        stem(app.UIAxes4,n, x_impar);
                    case 'Triangular'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.TiempoMuestrasEditField.Value;
                        n=-1:0.1:1;
                        app.TextArea.Value='Es una señal de potencia';
                        x = tripuls(n,amplitud);
                        stem(app.UIAxes,n,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        stem(app.UIAxes3,n, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        stem(app.UIAxes4,n, x_impar);
                end
            elseif app.PERIDICACheckBox.Value == true
                switch signal
                    case 'Cuadrática'
                        % INICIALIZACIÓN
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        % GRÁFICA
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        frecuencia = app.FrecuenciaEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        t = linspace(-tiempo, tiempo, 1000);
                        x = amplitud*square(2*pi*frecuencia*t);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Diente de Sierra'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        frecuencia = app.FrecuenciaEditField.Value;
                        ancho = app.AnchoEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        t = linspace(-tiempo, tiempo, 1000);
                        x = amplitud*sawtooth(2*pi*frecuencia*t,ancho);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Senoidal'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        frecuencia = app.FrecuenciaEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        t = linspace(-tiempo, tiempo, 1000);
                        phi = pi/2;
                        x = amplitud*sin(2*pi*frecuencia*t+phi);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes, t, x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Escalón'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        Fs = 10000;
                        t = -tiempo:1/Fs:tiempo;
                        x = amplitud*heaviside(t);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Rectangular'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);                        
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.TiempoMuestrasEditField.Value;
                        Fs = 10000;
                        t = -1:1/Fs:1;
                        x = rectpuls(t,amplitud);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Triangular'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.TiempoMuestrasEditField.Value;
                        Fs = 10000;
                        t = -2:1/Fs:2;
                        x = tripuls(t,amplitud);
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    otherwise
                        app.UIAxes.Visible =  "off";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "off";
                        app.UIAxes4.Visible =  "off";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        app.TextArea.Value='No es periodica';
                end
            elseif app.APERIDICACheckBox.Value == true
                switch signal
                    case 'Exponenciales'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "on";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "on";
                        app.UIAxes6.Visible =  "on";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, 'Exponencial Creciente');
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'f(t)');
                        title(app.UIAxes2, 'Exponencial Decreciente');
                        xlabel(app.UIAxes2, 'Tiempo(s)');
                        ylabel(app.UIAxes2, 'f(t)');
                        app.TextArea.Value='Es una señal de Energia';
                        tiempo = app.TiempoMuestrasEditField.Value;
                        alpha = app.AlphaEditField.Value;
                        Fs = 10000;
                        t = 0:1/Fs:tiempo;
                        x1 = exp(t);
                        plot(app.UIAxes,t,x1);
                        x2=alpha.^(-t);
                        plot(app.UIAxes2,t,x2);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par Exp. Creciente');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x1_par = (x1 + fliplr(x1))/2;
                        plot(app.UIAxes3,t, x1_par);
                        title(app.UIAxes4, 'Componente impar Exp. Creciente');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x1_impar = (x1 - fliplr(x1))/2;
                        plot(app.UIAxes4,t, x1_impar);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes5, 'Componente par Exp. Decreciente');
                        xlabel(app.UIAxes5, 'Tiempo(s)');
                        ylabel(app.UIAxes5, 'Amplitud');
                        x2_par = (x2 + fliplr(x2))/2;
                        plot(app.UIAxes5,t, x2_par);
                        title(app.UIAxes6, 'Componente impar Exp. Decreciente');
                        xlabel(app.UIAxes6, 'Tiempo(s)');
                        ylabel(app.UIAxes6, 'Amplitud');
                        x2_impar = (x2 - fliplr(x2))/2;
                        plot(app.UIAxes6,t, x2_impar);
                    case  'Exponencial Compleja'
                        app.RealButtonPushed();
                        app.ImaginariaButtonPushed();
                        app.MagnitudButtonPushed();
                        app.ArgumentoButtonPushed();
                        app.TextArea.Value='Es una señal de Energia';
                    case 'Impulso'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        app.TextArea.Value='Es una señal de Energia';
                        amplitud = app.AmplitudEditField.Value;
                        tiempo = app.TiempoMuestrasEditField.Value;
                        t = -tiempo:1:tiempo;
                        x = amplitud*[zeros(1,tiempo),ones(1,1),zeros(1,tiempo)];
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    case 'Rampa'
                        app.UIAxes.Visible =  "on";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "on";
                        app.UIAxes4.Visible =  "on";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        title(app.UIAxes, signal);
                        xlabel(app.UIAxes, 'Tiempo(s)');
                        ylabel(app.UIAxes, 'Amplitud');
                        amplitud = app.AmplitudEditField.Value;
                        Fs = 10000;
                        t1 = 0:1/Fs:5;
                        t2 = 5:1/Fs:10;
                        r = t1;
                        t = [t1,t2];
                        x = amplitud.*[zeros(1,length(t1)),r];
                        app.TextArea.Value='Es una señal de potencia';
                        plot(app.UIAxes,t,x);
                        % GRAFICA COMPONENTES
                        title(app.UIAxes3, 'Componente par');
                        xlabel(app.UIAxes3, 'Tiempo(s)');
                        ylabel(app.UIAxes3, 'Amplitud');
                        x_par = (x + fliplr(x))/2;
                        plot(app.UIAxes3,t, x_par);
                        title(app.UIAxes4, 'Componente impar');
                        xlabel(app.UIAxes4, 'Tiempo(s)');
                        ylabel(app.UIAxes4, 'Amplitud');
                        x_impar = (x - fliplr(x))/2;
                        plot(app.UIAxes4,t, x_impar);
                    otherwise
                        app.UIAxes.Visible =  "off";
                        app.UIAxes2.Visible =  "off";
                        app.UIAxes3.Visible =  "off";
                        app.UIAxes4.Visible =  "off";
                        app.UIAxes5.Visible =  "off";
                        app.UIAxes6.Visible =  "off";
                        cla(app.UIAxes);
                        cla(app.UIAxes2);
                        cla(app.UIAxes3);
                        cla(app.UIAxes4);
                        cla(app.UIAxes5);
                        cla(app.UIAxes6);
                        app.TextArea.Value='No es aperiodica';
                end
            end
        end

        % Button pushed function: RealButton
        function RealButtonPushed(app, event)
            app.UIAxes.Visible =  "on";
            app.UIAxes2.Visible =  "on";
            app.UIAxes3.Visible =  "off";
            app.UIAxes4.Visible =  "off";
            app.UIAxes5.Visible =  "on";
            app.UIAxes6.Visible =  "off";
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            if app.CONTINUACheckBox.Value ==  true
                if strcmp(app.SEALDropDown.Value, 'Exponencial Compleja')
                    title(app.UIAxes2, 'Parte Real');
                    xlabel(app.UIAxes2, 'Tiempo(s)');
                    ylabel(app.UIAxes2, 'Amplitud');
                    Fs=10000;
                    w=2*pi/1000;
                    T=2*pi/w;
                    t=-T*pi:1/Fs:T*pi;
                    x = exp(1j.*(2.*t+(pi/4)));
                    x_periodica=x(mod(t,T))<=T/2;
                    r = real(x_periodica);
                    plot(app.UIAxes2,t,r);
                    % GRAFICA COMPONENTES
                    title(app.UIAxes, 'Componente par');
                    xlabel(app.UIAxes, 'Tiempo(s)');
                    ylabel(app.UIAxes, 'Amplitud');
                    x_par = (r + fliplr(r))/2;
                    plot(app.UIAxes,t, x_par);
                    title(app.UIAxes5, 'Componente impar');
                    xlabel(app.UIAxes5, 'Tiempo(s)');
                    ylabel(app.UIAxes5, 'Amplitud');
                    x_impar = (r - fliplr(r))/2;
                    plot(app.UIAxes5,t, x_impar);
                end
            elseif app.DISCRETACheckBox.Value ==  true
                if strcmp(app.SEALDropDown.Value, 'Exponencial Compleja')
                    title(app.UIAxes2, 'Parte Real');
                    xlabel(app.UIAxes2, 'Tiempo(s)');
                    ylabel(app.UIAxes2, 'Amplitud');
                    w=2*pi/1000;
                    T=2*pi/w;
                    n=-T*pi:0.1:T*pi;
                    x = exp(1j.*(2.*n+(pi/4)));
                    x_periodica=x(mod(n,T))<=T/2;
                    r = real(x_periodica);
                    stem(app.UIAxes2,n,r);
                    % GRAFICA COMPONENTES
                    title(app.UIAxes, 'Componente par');
                    xlabel(app.UIAxes, 'Tiempo(s)');
                    ylabel(app.UIAxes, 'Amplitud');
                    x_par = (r + fliplr(r))/2;
                    stem(app.UIAxes,n, x_par);
                    title(app.UIAxes5, 'Componente impar');
                    xlabel(app.UIAxes5, 'Tiempo(s)');
                    ylabel(app.UIAxes5, 'Amplitud');
                    x_impar = (r - fliplr(r))/2;
                    stem(app.UIAxes5,n, x_impar);
                end
            end
        end

        % Button pushed function: ImaginariaButton
        function ImaginariaButtonPushed(app, event)
            app.UIAxes.Visible =  "on";
            app.UIAxes2.Visible =  "on";
            app.UIAxes3.Visible =  "off";
            app.UIAxes4.Visible =  "off";
            app.UIAxes5.Visible =  "on";
            app.UIAxes6.Visible =  "off";
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            if app.CONTINUACheckBox.Value ==  true
                if strcmp(app.SEALDropDown.Value, 'Exponencial Compleja')
                    title(app.UIAxes2, 'Parte Imaginaria');
                    xlabel(app.UIAxes2, 'Tiempo(s)');
                    ylabel(app.UIAxes2, 'Amplitud');
                    Fs=10000;
                    T = app.TiempoMuestrasEditField.Value;
                    t=-T*pi:1/Fs:T*pi;
                    x = exp(1j.*(2.*t+(pi/4)));    
                    im = imag(x);
                    plot(app.UIAxes2,t,im);
                    % GRAFICA COMPONENTES
                    title(app.UIAxes, 'Componente par');
                    xlabel(app.UIAxes, 'Tiempo(s)');
                    ylabel(app.UIAxes, 'Amplitud');
                    x_par = (im + fliplr(im))/2;
                    plot(app.UIAxes,t, x_par);
                    title(app.UIAxes5, 'Componente impar');
                    xlabel(app.UIAxes5, 'Tiempo(s)');
                    ylabel(app.UIAxes5, 'Amplitud');
                    x_impar = (x - fliplr(x))/2;
                    plot(app.UIAxes5,t, x_impar);
                end
                elseif app.DISCRETACheckBox.Value ==  true
                if strcmp(app.SEALDropDown.Value, 'Exponencial Compleja')
                    title(app.UIAxes2, 'Parte Imaginaria');
                    xlabel(app.UIAxes2, 'Tiempo(s)');
                    ylabel(app.UIAxes2, 'Amplitud');
                    T = app.TiempoMuestrasEditField.Value;
                    n=-T*pi:0.1:T*pi;
                    x = exp(1j.*(2.*n+(pi/4)));
                    im = imag(x);
                    stem(app.UIAxes2,n,im);
                    % GRAFICA COMPONENTES
                    title(app.UIAxes, 'Componente par');
                    xlabel(app.UIAxes, 'Tiempo(s)');
                    ylabel(app.UIAxes, 'Amplitud');
                    x_par = (im + fliplr(im))/2;
                    stem(app.UIAxes,n, x_par);
                    title(app.UIAxes5, 'Componente impar');
                    xlabel(app.UIAxes5, 'Tiempo(s)');
                    ylabel(app.UIAxes5, 'Amplitud');
                    x_impar = (im - fliplr(im))/2;
                    stem(app.UIAxes5,n, x_impar);
                end
            end
        end

        % Button pushed function: MagnitudButton
        function MagnitudButtonPushed(app, event)
            app.UIAxes.Visible =  "on";
            app.UIAxes2.Visible =  "on";
            app.UIAxes3.Visible =  "off";
            app.UIAxes4.Visible =  "off";
            app.UIAxes5.Visible =  "on";
            app.UIAxes6.Visible =  "off";
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            if app.CONTINUACheckBox.Value ==  true
                if strcmp(app.SEALDropDown.Value, 'Exponencial Compleja')
                    title(app.UIAxes2, 'Magnitud');
                    xlabel(app.UIAxes2, 'Tiempo(s)');
                    ylabel(app.UIAxes2, 'Amplitud');
                    Fs=10000;
                    T = app.TiempoMuestrasEditField.Value;
                    t=-T*pi:1/Fs:T*pi;
                    x = exp(1j.*(2.*t+(pi/4)));    
                    mag = abs(x);
                    plot(app.UIAxes2,t,mag);
                    % GRAFICA COMPONENTES
                    title(app.UIAxes, 'Componente par');
                    xlabel(app.UIAxes, 'Tiempo(s)');
                    ylabel(app.UIAxes, 'Amplitud');
                    x_par = (mag + fliplr(mag))/2;
                    plot(app.UIAxes,t, x_par);
                    title(app.UIAxes5, 'Componente impar');
                    xlabel(app.UIAxes5, 'Tiempo(s)');
                    ylabel(app.UIAxes5, 'Amplitud');
                    x_impar = (mag - fliplr(mag))/2;
                    plot(app.UIAxes5,t, x_impar);
                end
                elseif app.DISCRETACheckBox.Value ==  true
                if strcmp(app.SEALDropDown.Value, 'Exponencial Compleja')
                    title(app.UIAxes2, 'Magnitud');
                    xlabel(app.UIAxes2, 'Tiempo(s)');
                    ylabel(app.UIAxes2, 'Amplitud');
                    T = app.TiempoMuestrasEditField.Value;
                    n=-T*pi:0.1:T*pi;
                    x = exp(1j.*(2.*n+(pi/4)));
                    mag = abs(x);
                    stem(app.UIAxes2,n,mag);
                    % GRAFICA COMPONENTES
                    title(app.UIAxes, 'Componente par');
                    xlabel(app.UIAxes, 'Tiempo(s)');
                    ylabel(app.UIAxes, 'Amplitud');
                    x_par = (mag + fliplr(mag))/2;
                    stem(app.UIAxes,n, x_par);
                    title(app.UIAxes5, 'Componente impar');
                    xlabel(app.UIAxes5, 'Tiempo(s)');
                    ylabel(app.UIAxes5, 'Amplitud');
                    x_impar = (mag - fliplr(mag))/2;
                    stem(app.UIAxes5,n, x_impar);
                end
            end
        end

        % Button pushed function: ArgumentoButton
        function ArgumentoButtonPushed(app, event)
            app.UIAxes.Visible =  "on";
            app.UIAxes2.Visible =  "on";
            app.UIAxes3.Visible =  "off";
            app.UIAxes4.Visible =  "off";
            app.UIAxes5.Visible =  "on";
            app.UIAxes6.Visible =  "off";
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            if app.CONTINUACheckBox.Value ==  true
                if strcmp(app.SEALDropDown.Value, 'Exponencial Compleja')
                    title(app.UIAxes2, 'Argumento');
                    xlabel(app.UIAxes2, 'Tiempo(s)');
                    ylabel(app.UIAxes2, 'Amplitud');
                    Fs=10000;
                    T = app.TiempoMuestrasEditField.Value;
                    t=-T*pi:1/Fs:T*pi;
                    x = exp(1j.*(2.*t+(pi/4)));
                    r = real(x);
                    im = imag(x);
                    arg = atan(im./r);
                    plot(app.UIAxes2,t,arg);
                    % GRAFICA COMPONENTES
                    title(app.UIAxes, 'Componente par');
                    xlabel(app.UIAxes, 'Tiempo(s)');
                    ylabel(app.UIAxes, 'Amplitud');
                    x_par = (arg + fliplr(arg))/2;
                    plot(app.UIAxes,t, x_par);
                    title(app.UIAxes5, 'Componente impar');
                    xlabel(app.UIAxes5, 'Tiempo(s)');
                    ylabel(app.UIAxes5, 'Amplitud');
                    x_impar = (arg - fliplr(arg))/2;
                    plot(app.UIAxes5,t, x_impar);
                end
                elseif app.DISCRETACheckBox.Value ==  true
                if strcmp(app.SEALDropDown.Value, 'Exponencial Compleja') 
                    title(app.UIAxes2, 'Argumento');
                    xlabel(app.UIAxes2, 'Tiempo(s)');
                    ylabel(app.UIAxes2, 'Amplitud');
                    T = app.TiempoMuestrasEditField.Value;
                    n=-T*pi:0.1:T*pi;
                    x = exp(1j.*(2.*n+(pi/4)));
                    r = real(x);
                    im = imag(x);
                    arg = atan(im./r);
                    stem(app.UIAxes2,n,arg);
                    % GRAFICA COMPONENTES
                    title(app.UIAxes, 'Componente par');
                    xlabel(app.UIAxes, 'Tiempo(s)');
                    ylabel(app.UIAxes, 'Amplitud');
                    x_par = (arg + fliplr(arg))/2;
                    stem(app.UIAxes,n, x_par);
                    title(app.UIAxes5, 'Componente impar');
                    xlabel(app.UIAxes5, 'Tiempo(s)');
                    ylabel(app.UIAxes5, 'Amplitud');
                    x_impar = (arg - fliplr(arg))/2;
                    stem(app.UIAxes5,n, x_impar);
                end
            end
        end

        % Value changed function: SEALDropDown
        function SEALDropDownValueChanged(app, event)
            if strcmp(app.SEALDropDown.Value, 'Exponencial Compleja') 
                app.RealButton.Visible = "on";
                app.ImaginariaButton.Visible = "on";
                app.MagnitudButton.Visible = "on";
                app.ArgumentoButton.Visible = "on";
                app.GRAFICARButton.Visible = "off";
            else
                app.RealButton.Visible = "off";
                app.ImaginariaButton.Visible = "off";
                app.MagnitudButton.Visible = "off";
                app.ArgumentoButton.Visible = "off";
                app.GRAFICARButton.Visible = "on";
            end
        end

        % Value changed function: PERIDICACheckBox
        function PERIDICACheckBoxValueChanged(app, event)
            if app.PERIDICACheckBox.Value
                app.CONTINUACheckBox.Enable = 'off';
                app.DISCRETACheckBox.Enable = 'off';
                app.APERIDICACheckBox.Enable = 'off';
                app.SEALDropDown.Enable = 'on';
            else
                app.CONTINUACheckBox.Enable = 'on';
                app.DISCRETACheckBox.Enable = 'on';
                app.APERIDICACheckBox.Enable = 'on';
                app.SEALDropDown.Enable = 'off';
                cla(app.UIAxes);
            end
        end

        % Value changed function: APERIDICACheckBox
        function APERIDICACheckBoxValueChanged(app, event)
            if app.APERIDICACheckBox.Value
                app.CONTINUACheckBox.Enable = 'off';
                app.PERIDICACheckBox.Enable = 'off';
                app.DISCRETACheckBox.Enable = 'off';
                app.SEALDropDown.Enable = 'on';
            else
                app.CONTINUACheckBox.Enable = 'on';
                app.PERIDICACheckBox.Enable = 'on';
                app.DISCRETACheckBox.Enable = 'on';
                app.SEALDropDown.Enable = 'off';
                cla(app.UIAxes);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.GRAFICARButton = uibutton(app.UIFigure, 'push');
            app.GRAFICARButton.ButtonPushedFcn = createCallbackFcn(app, @GRAFICARButtonPushed, true);
            app.GRAFICARButton.Text = 'GRAFICAR';
            app.GENERADORDESEALESPanel = uipanel(app.UIFigure);
            app.GENERADORDESEALESPanel.Title = 'GENERADOR DE SEÑALES';
            app.CONTINUACheckBox = uicheckbox(app.GENERADORDESEALESPanel);
            app.CONTINUACheckBox.ValueChangedFcn = createCallbackFcn(app, @CONTINUACheckBoxValueChanged, true);
            app.CONTINUACheckBox.Text = 'CONTINUA';
            app.DISCRETACheckBox = uicheckbox(app.GENERADORDESEALESPanel);
            app.DISCRETACheckBox.ValueChangedFcn = createCallbackFcn(app, @DISCRETACheckBoxValueChanged, true);
            app.DISCRETACheckBox.Text = 'DISCRETA';
            app.SEALDropDownLabel = uilabel(app.GENERADORDESEALESPanel);
            app.SEALDropDownLabel.Text = 'SEÑAL';
            app.SEALDropDown = uidropdown(app.GENERADORDESEALESPanel);
            app.SEALDropDown.Items = {'Seleccione una opción', 'Cuadrática', 'Diente de Sierra', 'Senoidal', 'Exponenciales', 'Exponencial Compleja', 'Escalón', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.SEALDropDown.ValueChangedFcn = createCallbackFcn(app, @SEALDropDownValueChanged, true);
            app.SEALDropDown.Value = 'Seleccione una opción';
            app.PERIDICACheckBox = uicheckbox(app.GENERADORDESEALESPanel);
            app.PERIDICACheckBox.ValueChangedFcn = createCallbackFcn(app, @PERIDICACheckBoxValueChanged, true);
            app.PERIDICACheckBox.Text = 'PERIÓDICA';
            app.APERIDICACheckBox = uicheckbox(app.GENERADORDESEALESPanel);
            app.APERIDICACheckBox.ValueChangedFcn = createCallbackFcn(app, @APERIDICACheckBoxValueChanged, true);
            app.APERIDICACheckBox.Text = 'APERIÓDICA';
            app.AmplitudEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciaEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodosEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodosEditFieldLabel.Text = '# Periodos';
            app.PeriodosEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnchoEditFieldLabel = uilabel(app.UIFigure);
            app.AnchoEditFieldLabel.Text = 'Ancho';
            app.AnchoEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiempoMuestrasEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoMuestrasEditFieldLabel.Text = 'Tiempo/Muestras';
            app.TiempoMuestrasEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinearectaEditFieldLabel = uilabel(app.UIFigure);
            app.LinearectaEditFieldLabel.Text = 'Linea recta';
            app.LinearectaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PendienteEditFieldLabel = uilabel(app.UIFigure);
            app.PendienteEditFieldLabel.Text = 'Pendiente';
            app.PendienteEditField = uieditfield(app.UIFigure, 'numeric');
            app.RealButton = uibutton(app.UIFigure, 'push');
            app.RealButton.ButtonPushedFcn = createCallbackFcn(app, @RealButtonPushed, true);
            app.RealButton.Text = 'Real';
            app.ImaginariaButton = uibutton(app.UIFigure, 'push');
            app.ImaginariaButton.ButtonPushedFcn = createCallbackFcn(app, @ImaginariaButtonPushed, true);
            app.ImaginariaButton.Text = 'Imaginaria';
            app.MagnitudButton = uibutton(app.UIFigure, 'push');
            app.MagnitudButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudButtonPushed, true);
            app.MagnitudButton.Text = 'Magnitud';
            app.ArgumentoButton = uibutton(app.UIFigure, 'push');
            app.ArgumentoButton.ButtonPushedFcn = createCallbackFcn(app, @ArgumentoButtonPushed, true);
            app.ArgumentoButton.Text = 'Argumento';
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica4_EquipoE1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end