classdef Practica4_czarin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Generadordesenal            matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Prctica4Label               matlab.ui.control.Label
        TipodeTiempoDropDownLabel   matlab.ui.control.Label
        TipodeTiempoDropDown        matlab.ui.control.DropDown
        TipodeSeal1DropDownLabel_6  matlab.ui.control.Label
        TipodeSeal1DropDownLabel_5  matlab.ui.control.Label
        TipodeSeal1DropDownLabel_4  matlab.ui.control.Label
        TipodeSeal1DropDownLabel_3  matlab.ui.control.Label
        Expandir                    matlab.ui.control.CheckBox
        Izquierda                   matlab.ui.control.CheckBox
        Comprimir                   matlab.ui.control.CheckBox
        Derecha                     matlab.ui.control.CheckBox
        EscalarEditField            matlab.ui.control.NumericEditField
        EscalarEditFieldLabel       matlab.ui.control.Label
        RecorrerEditField           matlab.ui.control.NumericEditField
        RecorrerEditFieldLabel      matlab.ui.control.Label
        Tiempo2                     matlab.ui.control.NumericEditField
        Amplitud2                   matlab.ui.control.NumericEditField
        Frecuencia2                 matlab.ui.control.NumericEditField
        TipodeSeal1DropDownLabel_2  matlab.ui.control.Label
        TipodeSeal1DropDown_2       matlab.ui.control.DropDown
        LimpiarSealButton           matlab.ui.control.Button
        OperacinDropDown            matlab.ui.control.DropDown
        OperacinDropDownLabel       matlab.ui.control.Label
        Elija1sealDropDown          matlab.ui.control.DropDown
        Elija1sealLabel             matlab.ui.control.Label
        GenerarSealButton           matlab.ui.control.Button
        TiempoEditField             matlab.ui.control.NumericEditField
        TiempoEditFieldLabel        matlab.ui.control.Label
        AmplitudEditField           matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel      matlab.ui.control.Label
        FrecuenciaEditField         matlab.ui.control.NumericEditField
        FrecuenciaEditField_2Label  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarSealButton
        function GenerarSealButtonPushed(app, event)
            % Obtener los valores ingresados por el usuario
            Amplitud = app.AmplitudEditField.Value;
            Frecuencia = app.FrecuenciaEditField.Value;
            Tiempo = app.TiempoEditField.Value;
            Amplitud_2 = app.Amplitud2.Value;
            Frecuencia_2 = app.Frecuencia2.Value;
            Tiempo_2 = app.Tiempo2.Value;
            TipoTiempo = app.TipodeTiempoDropDown.Value;
            Dato1 = app.Elija1sealDropDown.Value;
            Dato2 = app.TipodeSeal1DropDown_2.Value;
            E = app.EscalarEditField.Value;
            Recorrer=app.RecorrerEditField.Value;
            if(E == 0)
                E = 1;
            end
            Tiempo = (E.*Tiempo) + Recorrer;


            % Datos de la señal
            switch Dato1
                case 'Senoidal'
                    
                    if strcmp(TipoTiempo,'Continuo')
                        Fs=10000;
                        t=0:Tiempo/Fs:Tiempo;
                        
                        y = Amplitud*sin(2*pi*Frecuencia*t);
                        plot(app.UIAxes,t,y),grid on
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Senoidal en tiempo continuo')
                        
                    else %si es discreto
                        n = 0:Tiempo; % Vector de tiempo discreto
                        phi = pi/2; % Fase de la señal
                        y = Amplitud*sin(2*pi*Frecuencia*n - phi); % Señal senoidal
                        %Grafica de la señal
                        stem(app.UIAxes,n,y),grid on
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Senoidal en tiempo discreto')
                    end
                case 'Cuadrada'
                    if strcmp(TipoTiempo,'Continuo')
                        Fs = 10000; %Frecuencia de Muestreo
                        duty=50;
                        t = 0:1/Fs:Tiempo-1/Fs; %Tiempo de Simulación
                        y = Amplitud*square(2*pi*Frecuencia*t,duty);
                        %Grafica de la señal
                        plot(app.UIAxes,t,y,'r'),grid on
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Cuadrada en tiempo continuo')
                        
                    else %si es discreto
                        duty = 50; %Ciclo útil
                        n = 0:Tiempo; %Tiempo de Simulación
                        y = Amplitud*square(2*pi*Frecuencia*n,duty); %Generación de Señal
                        stem(app.UIAxes,n,y,'r'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Cuadrada en tiempo discreto')
                        
                    end
                case 'Exponencial Creciente'
                    if strcmp(TipoTiempo,'Continuo')
                        t=0:1/Frecuencia:Tiempo;
                        y=exp(t);
                        plot(app.UIAxes,t,y,'k'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Exponencial Creciente en tiempo continuo')
                        
                    else %si es discreto
                        n = 0:Tiempo; %Tiempo de Simulación
                        y = exp(n); %Generación de Señal
                        stem(app.UIAxes,n,y,'k'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Exponencial Creciente en tiempo discreto')
                        
                    end
                case 'Exponencial Decreciente'
                    if strcmp(TipoTiempo,'Continuo')
                        r = 1.5;
                        t=0:1/Frecuencia:Tiempo;
                        y=r.^(-t);
                        plot(app.UIAxes,t,y,'k'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Exponencial Decreciente en tiempo continuo')
                        
                    else %si es dicreto
                        n = 0:Tiempo;
                        r = 1.5;
                        y = r.^(-n); %Generación de Señal
                        stem(app.UIAxes,n,y,'k'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Exponencial Decreciente en tiempo discreto')
                        
                    end
                case 'Triangular'
                    if strcmp(TipoTiempo,'Continuo')
                        t=-Tiempo:1/Frecuencia:Tiempo;
                        y= Amplitud.*tripuls(t);
                        plot(app.UIAxes,t,y,"m"),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Triangular en tiempo continuo')
                    else %si es discreto
                        n = -Tiempo:0.1:Tiempo; %Tiempo de Simulación
                        y = Amplitud.*tripuls(n); %Generación de Señal
                        stem(app.UIAxes,n,y,'m*'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Triangular en tiempo discreto')
                        
                    end
                case 'Diente de Sierra'
                    if strcmp(TipoTiempo,'Continuo')
                        Fs = 10000; %Frecuencia de Muestreo
                        width=0.5;
                        t= 0:1/Fs:Tiempo-1/Fs;
                        y= Amplitud*sawtooth(2*pi*Frecuencia*t,width);
                        plot(app.UIAxes,t,y),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Dientes de Sierra en tiempo continuo')
                    else %si es discreto
                        width = 0.5; %Ancho
                        n = 0:Tiempo; %Tiempo de Simulación
                        y = Amplitud*sawtooth(2*pi*Frecuencia*n,width); %Generación de Señal
                        stem(app.UIAxes,n,y),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Dientes de Sierra en tiempo discreto')
                    end

                case 'Rampa'
                    if strcmp(TipoTiempo, 'Continuo')
                        Fs=1000;
                        t1 = 0 : 1/Fs : Tiempo/2;
                        t2 = Tiempo/2: 1/Fs :Tiempo;

                        r=t1;

                        t=[t1,t2];

                        y=Amplitud.*[zeros(1,length(t1)),r];

                        plot(app.UIAxes, t, y), grid on;
                        xlabel('Tiempo (s)');
                        ylabel('Amplitud');
                        title(app.UIAxes, 'Señal de Rampa');
                    
                    else % Es tiempo discreto
                       
                        n1 = 0: Tiempo/2;
                        n2 = Tiempo/2: Tiempo;

                        r=n1;

                        n=[n1,n2];

                        y=Amplitud.*[zeros(1,length(n1)),r];

                        stem(app.UIAxes, n, y), grid on;
                        xlabel('Muestras (n)');
                        ylabel('Amplitud');
                        title(app.UIAxes, 'Señal de Rampa (Discreto)');
                    end

                case 'Escalón'
                    if strcmp(TipoTiempo,'Continuo')
                        t = -Tiempo:1/Frecuencia:Tiempo; %Tiempo de Simulación
                        % Generación de las señales
                        y = Amplitud.*ones(1,length(t)); % Señal escalón
                        
                        % Graficar las señales en la misma figura
                        % Mantener la figura actual y agregar más elementos
                        plot(app.UIAxes,t, y, 'b', 'LineWidth', 2) % Señal escalón en azul
                        
                        grid on % Agregar la rejilla
                        xlabel('Tiempo (s)') % Etiqueta del eje x
                        ylabel('Amplitud') % Etiqueta del eje y
                        legend('Escalón con heaviside', 'Escalón con ones') % Leyenda
                        
                    else %si es discreto
                        n = 0:Tiempo; %Tiempo de Simulación
                        y = Amplitud.*ones(1,length(n)); %Generación de Señal
                        stem(app.UIAxes,n,y),grid on
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con heaviside')
                    end
                    
                case 'Impulso Triangular'
                    if strcmp(TipoTiempo,'Continuo')
                        %Generación de Señal
                        t = -Tiempo:Tiempo; %Tiempo de Simulación
                        y=sawtooth(2*pi*t, 0.5);
                        plot(app.UIAxes,t,y),grid on
                        title(app.UIAxes,'Impulso en tiempo continuo')
                    else %si es discreto
                        n = 0:5; %Tiempo de Simulación
                        y = Amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)];
                        stem(app.UIAxes,n,y),grid on,xlim([0 5]),ylim([0 1.1])
                        title(app.UIAxes,'Impulso en tiempo discreto')
                    end
                
            end
            
            switch Dato2
                case 'Senoidal'
                    
                    if strcmp(TipoTiempo,'Continuo')
                        Fs=10000;
                        t2=0:Tiempo_2/Fs:Tiempo_2;
                        y2 = Amplitud_2*sin(2*pi*Frecuencia_2*t2);
                        plot(app.UIAxes2,t2,y2),grid on
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Senoidal en tiempo continuo')
                        
                    else %si es discreto
                        n = 0:Tiempo_2; % Vector de tiempo discreto
                        phi = pi/2; % Fase de la señal
                        y2 = Amplitud_2*sin(2*pi*Frecuencia_2*n - phi); % Señal senoidal
                        %Grafica de la señal
                        stem(app.UIAxes2,n,y2),grid on
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Senoidal en tiempo discreto')
                    end
                case 'Cuadrada'
                    if strcmp(TipoTiempo,'Continuo')
                        Fs = 10000; %Frecuencia de Muestreo
                        duty=50;
                        t2 = -Tiempo_2:1/Fs:Tiempo_2-1/Fs; %Tiempo de Simulación
                        y2 = Amplitud_2*square(2*pi*Frecuencia_2*t2,duty);
                        %Grafica de la señal
                        plot(app.UIAxes2,t2,y2,'r'),grid on
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Cuadrada en tiempo continuo')
                        
                    else %si es discreto
                        duty = 50; %Ciclo útil
                        n = 0:Tiempo_2; %Tiempo de Simulación
                        y2 = Amplitud_2*square(2*pi*Frecuencia_2*n,duty); %Generación de Señal
                        stem(app.UIAxes2,n,y2,'r'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Cuadrada en tiempo discreto')
                        
                    end
                case 'Exponencial Creciente'
                    if strcmp(TipoTiempo,'Continuo')
                        t2=-Tiempo_2:1/Frecuencia_2:Tiempo_2;
                        y2=exp(t2);
                        plot(app.UIAxes2,t2,y2,'k'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Exponencial Creciente en tiempo continuo')
                        
                    else %si es discreto
                        n = 0:Tiempo_2; %Tiempo de Simulación
                        y2 = exp(n); %Generación de Señal
                        stem(app.UIAxes2,n,y2,'k'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Exponencial Creciente en tiempo discreto')
                        
                    end
                case 'Exponencial Decreciente'
                    if strcmp(TipoTiempo,'Continuo')
                        r = 1.5;
                        t2=0:1/Frecuencia_2:Tiempo_2;
                        y2=r.^(-t2);
                        plot(app.UIAxes2,t2,y2,'k'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Exponencial Decreciente en tiempo continuo')
                        
                    else %si es dicreto
                        n = 0:Tiempo_2;
                        r = 1.5;
                        y2 = r.^(-n); %Generación de Señal
                        stem(app.UIAxes2,n,y2,'k'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Exponencial Decreciente en tiempo discreto')
                        
                    end
                case 'Triangular'
                    if strcmp(TipoTiempo,'Continuo')
                        t2=-Tiempo_2:1/Frecuencia_2:Tiempo_2;
                        y2= Amplitud_2.*tripuls(t2);
                        plot(app.UIAxes2,t2,y2,"m"),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Triangular en tiempo continuo')
                    else %si es discreto
                        n = -Tiempo_2:0.1:Tiempo_2; %Tiempo de Simulación
                        y2 = Amplitud_2.*tripuls(n); %Generación de Señal
                        stem(app.UIAxes2,n,y2,'m*'),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Triangular en tiempo discreto')
                        
                    end

                case 'Diente de Sierra'
                    if strcmp(TipoTiempo,'Continuo')
                        Fs = 10000; %Frecuencia de Muestreo
                        width=0.5;
                        t2= 0:1/Fs:Tiempo_2-1/Fs;
                        y2= Amplitud_2*sawtooth(2*pi*Frecuencia_2*t2,width);
                        plot(app.UIAxes2,t2,y2),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Dientes de Sierra en tiempo continuo')
                    else %si es discreto
                        width = 0.5; %Ancho
                        n = 0:Tiempo_2; %Tiempo de Simulación
                        y2 = Amplitud_2*sawtooth(2*pi*Frecuencia_2*n,width); %Generación de Señal
                        stem(app.UIAxes2,n,y2),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Dientes de Sierra en tiempo discreto')
                    end

                case 'Rampa'
                    if strcmp(TipoTiempo, 'Continuo')
                        Fs = 10000; % Frecuencia de Muestreo
                        t2 = 0:1/Fs:10; % Intervalo de tiempo completo
                        y2 = Amplitud_2 .* t2 .* (t2 <= 10); % Generación de señal de rampa en [0, 10]

                        % Gráfica
                        plot(app.UIAxes2, t2, y2), grid on;
                        xlabel('Tiempo (s)');
                        ylabel('Amplitud');
                        title(app.UIAxes2, 'Señal de Rampa');
                    else % Es tiempo discreto
                        n = 0:10; % Valores de tiempo discreto
                        y2 = Amplitud_2 .* n; % Generación de señal de rampa en tiempo discreto

                        % Gráfica en tiempo discreto
                        stem(app.UIAxes2, n, y2), grid on;
                        xlabel('Muestras (n)');
                        ylabel('Amplitud');
                        title(app.UIAxes2, 'Señal de Rampa (Discreto)');
                    end


                case 'Escalón'
                    if strcmp(TipoTiempo,'Continuo')
                        t2 = -Tiempo_2:1/Frecuencia_2:Tiempo_2; %Tiempo de Simulación
                        % Generación de las señales
                        y2 = Amplitud_2.*ones(1,length(t2)); % Señal escalón
                        % Mantener la figura actual y agregar más elementos
                        plot(app.UIAxes2,t2, y2, 'b', 'LineWidth', 2) % Señal escalón en azul
                        grid on % Agregar la rejilla
                        xlabel('Tiempo (s)') % Etiqueta del eje x
                        ylabel('Amplitud') % Etiqueta del eje y
                        legend('Escalón con heaviside', 'Escalón con ones') % Leyenda
                        
                    else %si es discreto
                        n = -Tiempo_2:Tiempo_2; %Tiempo de Simulación
                        y2 = Amplitud.*ones(1,length(n)); % Señal escalón
                        stem(app.UIAxes2,n,y2),grid on
                        title(app.UIAxes2,'EScalon en tiempo discreto')
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con heaviside')
                    end


            end

            switch app.OperacinDropDown.Value

                case 'Suma'

                    % Verificar si las señales tienen la misma longitud
                    if length(y) == length(y2)
                        % Si las señales tienen la misma longitud, sumarlas directamente
                        Suma_funciones = y + y2;
                    else
                        % Si las señales tienen longitud diferente, sumarlas y truncar el resultado
                        n = min(length(y), length(y2));
                        Suma_funciones = y(1:n) + y2(1:n);
                    end

                    plot(app.UIAxes3,t,Suma_funciones,'m');
                    grid on;
                    title(app.UIAxes3,'Suma de señal 1 y señal 2');

                case 'Resta'
                    % Verificar si las señales tienen la misma longitud
                    if length(y) == length(y2)
                        % Si las señales tienen la misma longitud, sumarlas directamente
                        Resta_funciones = y - y2;
                    else
                        % Si las señales tienen longitud diferente, truncar la señal más larga
                        n = min(length(y), length(y2));
                        Resta_funciones = y(1:n) - y2(1:n);
                    end
                    
                    plot(app.UIAxes3,t,Resta_funciones,'m');
                    grid on;
                    title(app.UIAxes3,'Resta de señal 1 y señal 2');
                    
                    
                case 'Multiplicacion'
                    % Verificar si las señales tienen la misma longitud
                    if length(y) == length(y2)
                        % Si las señales tienen la misma longitud, sumarlas directamente
                        Multi_funciones = y;
                    else
                        % Si las señales tienen longitud diferente, truncar la señal más larga
                        n = min(length(y), length(y2));
                        Multi_funciones = y(1:n) .* y2(1:n);
                    end
                    
                    plot(app.UIAxes3,t,Multi_funciones,'k');
                    grid on;
                    title(app.UIAxes3,'Multiplicación de señal 1 y señal 2');
                    
                    
                case 'Division'
                    % Verificar si las señales tienen la misma longitud
                    if length(y) == length(y2)
                        % Si las señales tienen la misma longitud, sumarlas directamente
                        Div_funciones = y ./ y2;
                    else
                        % Si las señales tienen longitud diferente, truncar la señal más larga
                        n = min(length(y), length(y2));
                        Div_funciones = y(1:n) ./ y2(1:n);
                    end
                    
                    plot(app.UIAxes3,t,Div_funciones,'k');
                    grid on;
                    title(app.UIAxes3,'División de señal 1 y señal 2');
                    
                case 'Reflexion'
                    % Verificar si las señales tienen la misma longitud
                    if length(y) == length(y2)
                        % Si las señales tienen la misma longitud, sumarlas directamente
                        x1_ref = -y; % señal 1 reflejada
                        x2_ref = -y2; % señal 2 reflejada
                    else
                        % Si las señales tienen longitud diferente, truncar la señal más larga
                        n = min(length(y), length(y2));
                        x1_ref = -y(1:n); % señal 1 reflejada
                        x2_ref = -y2(1:n); % señal 2 reflejada
                    end
                    
                    plot(app.UIAxes3,x1_ref,'k');
                    hold(app.UIAxes3, 'on');
                    plot(app.UIAxes3,x2_ref,'m');
                    hold(app.UIAxes3, 'off');
                    grid on;
                    title(app.UIAxes3,'Reflexion de señal 1 y señal 2');
                    
                    
                case 'Desplazamiento'
                    Recorrer=app.RecorrerEditField.Value;
                    if(app.Derecha.Value==1)
                        tD_1 = Tiempo+Recorrer;
                       
                        plot(app.UIAxes4,tD_1,y,'-'), grid on
                        hold(app.UIAxes4, 'on');

                        plot(app.UIAxes4,tD_2,y,'k'), grid on
                        hold(app.UIAxes4, 'off');
                        xlabel(app.UIAxes4, 'Tiempo desplazado')
                        ylabel(app.UIAxes4, 'Amplitud')
                        title(app.UIAxes4,'Desplazamiento a la derecha');
                    elseif(app.Izquierda.Value==1)
                        tI_1 = Tiempo-Recorrer;
                      
                       
                        plot(app.UIAxes4,tI_1,y,'-', 'y'), grid on
                        hold(app.UIAxes4, 'on');
                        plot(app.UIAxes4,tI_2,'-', 'm'), grid on
                        xlabel(app.UIAxes4, 'Tiempo desplazado')
                        ylabel(app.UIAxes4, 'Amplitud')
                        title(app.UIAxes4,'Desplazamiento a la izquierda');
                    end


                case 'Integracion'
                    if length(y) == length(y2)
                        % Si las señales tienen la misma longitud
                        x_uniform = linspace(t(1), t(end), numel(t));
                        x1_int = cumtrapz(x_uniform, y); % señal 1 integrada
                        
                        
                    else
                        % Si las señales tienen longitud diferente,
                        n = min(length(y), length(y2));
                        x_uniform_ = linspace(t(1), t(end), numel(t));
                        x1_int = cumtrapz(x_uniform_, y(1:n)); % señal 1 integrada
                        
                        
                    end
                    plot(app.UIAxes3,t,x1_int,'k');
                    grid on;
                    title(app.UIAxes3,'Integracion');
                    
                    
                case 'Escalamiento'
                    E = app.EscalarEditField.Value;
                    
                    if (app.Comprimir.Value == 1)
                        A_new1_com = y./E;
                        A_new_com= y2./E;
                        plot(app.UIAxes3,t,A_new1_com,"m"), grid on
                        hold(app.UIAxes3, 'on');
                        plot(app.UIAxes3,t2,A_new_com), grid on
                        hold(app.UIAxes3, 'off');
                        title(app.UIAxes3,'Escalamiento reducido');
                    elseif(app.Expandir.Value == 1)
                        A_new1_exp = y*E;
                        A_new_exp = y2*E;
                        
                        plot(app.UIAxes3,A_new1_exp,"m")
                        plot(app.UIAxes3,A_new_exp)
                        hold(app.UIAxes3, 'off');
                        title(app.UIAxes3,'Escalamiento expandido');
                    end
                  
                    
                case 'Diferenciacion'
                    if length(y) == length(y2)
                        % Si las señales tienen la misma longitud
                        x1_diff = diff(y); % señal 1 diferenciada
                        
                    else
                        % Si las señales tienen longitud diferente,
                        n = min(length(y), length(y2));
                        x1_diff = diff(y(1:n)); % señal 1 diferenciada

                    end


                    end


        end

        % Button pushed function: LimpiarSealButton
        function LimpiarSealButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3); % Limpia los datos de la gráfica
            cla(app.UIAxes4);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Generadordesenal.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {531, 531};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Generadordesenal = uifigure('Visible', 'off');
            app.Generadordesenal.Name = 'Prac_4_czarin y aldrich';
            app.Generadordesenal.Icon = fullfile(pathToMLAPP, 'BEAST.jpg');
            app.Generadordesenal.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Generadordesenal);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FrecuenciaEditField_2Label = uilabel(app.LeftPanel);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempoEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GenerarSealButton = uibutton(app.LeftPanel, 'push');
            app.GenerarSealButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarSealButtonPushed, true);
            app.GenerarSealButton.Text = 'Generar Señal';
            app.Elija1sealLabel = uilabel(app.LeftPanel);
            app.Elija1sealLabel.Text = 'Elija 1ª señal';
            app.Elija1sealDropDown = uidropdown(app.LeftPanel);
            app.Elija1sealDropDown.Items = {'Cuadrada', 'Exponencial Creciente', 'Exponencial Decreciente', 'Triangular', 'Diente de Sierra', 'Escalón', 'Rampa', 'Senoidal'};
            app.Elija1sealDropDown.Value = 'Cuadrada';
            app.OperacinDropDownLabel = uilabel(app.LeftPanel);
            app.OperacinDropDownLabel.Text = 'Operación';
            app.OperacinDropDown = uidropdown(app.LeftPanel);
            app.OperacinDropDown.Items = {'Escalamiento', 'Suma', 'Resta', 'Multiplicacion', 'Division', 'Diferenciacion', 'Integracion', 'Desplazamiento', 'Reflexion', 'Funcion'};
            app.OperacinDropDown.Value = 'Suma';
            app.LimpiarSealButton = uibutton(app.LeftPanel, 'push');
            app.LimpiarSealButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarSealButtonPushed, true);
            app.LimpiarSealButton.Text = 'Limpiar Señal';
            app.TipodeSeal1DropDown_2 = uidropdown(app.LeftPanel);
            app.TipodeSeal1DropDown_2.Items = {'Cuadrada', 'Exponencial Creciente', 'Exponencial Decreciente', 'Triangular', 'Diente de Sierra', 'Escalón', 'Rampa', 'Senoidal'};
            app.TipodeSeal1DropDown_2.Value = 'Cuadrada';
            app.TipodeSeal1DropDownLabel_2 = uilabel(app.LeftPanel);
            app.TipodeSeal1DropDownLabel_2.Text = 'Elija 2ª señal';
            app.Frecuencia2 = uieditfield(app.LeftPanel, 'numeric');
            app.Amplitud2 = uieditfield(app.LeftPanel, 'numeric');
            app.Tiempo2 = uieditfield(app.LeftPanel, 'numeric');
            app.RecorrerEditFieldLabel = uilabel(app.LeftPanel);
            app.RecorrerEditFieldLabel.Text = 'Recorrer';
            app.RecorrerEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EscalarEditFieldLabel = uilabel(app.LeftPanel);
            app.EscalarEditFieldLabel.Text = 'Escalar';
            app.EscalarEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Derecha = uicheckbox(app.LeftPanel);
            app.Derecha.Text = '';
            app.Comprimir = uicheckbox(app.LeftPanel);
            app.Comprimir.Text = '';
            app.Izquierda = uicheckbox(app.LeftPanel);
            app.Izquierda.Text = '';
            app.Expandir = uicheckbox(app.LeftPanel);
            app.Expandir.Text = '';
            app.TipodeSeal1DropDownLabel_3 = uilabel(app.LeftPanel);
            app.TipodeSeal1DropDownLabel_3.Text = 'IZQ';
            app.TipodeSeal1DropDownLabel_4 = uilabel(app.LeftPanel);
            app.TipodeSeal1DropDownLabel_4.Text = 'DER';
            app.TipodeSeal1DropDownLabel_5 = uilabel(app.LeftPanel);
            app.TipodeSeal1DropDownLabel_5.Text = 'EXPA';
            app.TipodeSeal1DropDownLabel_6 = uilabel(app.LeftPanel);
            app.TipodeSeal1DropDownLabel_6.Text = 'COMP';
            app.TipodeTiempoDropDown = uidropdown(app.LeftPanel);
            app.TipodeTiempoDropDown.Items = {'Continuo', 'Discreto'};
            app.TipodeTiempoDropDown.Value = 'Continuo';
            app.TipodeTiempoDropDownLabel = uilabel(app.LeftPanel);
            app.TipodeTiempoDropDownLabel.Text = 'Tipo de Tiempo';
            app.Prctica4Label = uilabel(app.LeftPanel);
            app.Prctica4Label.Text = 'Práctica 4';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.Generadordesenal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica4_czarin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Generadordesenal)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Generadordesenal)
        end
    end
end