classdef PracticaConvolu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AlgoritmoConvolucionButton  matlab.ui.control.Button
        ConvolucinButton            matlab.ui.control.Button
        EscalamientodeTiempoSlider  matlab.ui.control.Slider
        EscalamientodeTiempoSliderLabel  matlab.ui.control.Label
        DesplazamientoSlider        matlab.ui.control.Slider
        DesplazamientoSliderLabel   matlab.ui.control.Label
        IntegracinButton            matlab.ui.control.Button
        SealDiscretaLabel           matlab.ui.control.Label
        SealContinuaLabel           matlab.ui.control.Label
        DiferenciacinButton         matlab.ui.control.Button
        MultiplicacinButton         matlab.ui.control.Button
        SumaButton                  matlab.ui.control.Button
        ListBox                     matlab.ui.control.ListBox
        AlphaSlider                 matlab.ui.control.Slider
        AlphaSliderLabel            matlab.ui.control.Label
        PulsoSlider                 matlab.ui.control.Slider
        PulsoSliderLabel            matlab.ui.control.Label
        VectorTiempoSlider          matlab.ui.control.Slider
        VectorTiempoSliderLabel     matlab.ui.control.Label
        Practica4Label              matlab.ui.control.Label
        FrecuenciaSlider            matlab.ui.control.Slider
        FrecuenciaSliderLabel       matlab.ui.control.Label
        AmplitudSlider              matlab.ui.control.Slider
        AmplitudSliderLabel         matlab.ui.control.Label
        SealesLabel                 matlab.ui.control.Label
        GraficasLabel               matlab.ui.control.Label
        Senales                     matlab.ui.control.ListBox
        PeriodicidadCheckBox        matlab.ui.control.CheckBox
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        senal1 % Señal 1    Continua
        senal2 % Señal 2    Continua
        senal3 % Señal 3    Discreta
        senal4 % Señal 4    Discreta
        ks
        ki
        ls
        li
        tiempo % Tiempo Continuo
        muestreo %Tiempo Discreto
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Senales
        function SenalesValueChanged(app, event)
            value = app.Senales.Value;
            
            if value == "Escalon"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                a=app.AmplitudSlider.Value;
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de tiempo Discreto
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo
          
                u = zeros(size(t));
                u(t >= 0-t0) = a*1;
                v = zeros(1,N);
                v (n >= 0-t0) = a*1;
                
            elseif value == "Pulso"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                A=app.AmplitudSlider.Value;                     % Amplitud
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de tiempo Discreto
                sw=app.PeriodicidadCheckBox.Value;
                T = app.PulsoSlider.Value;                      % Longitud de Pulso
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo
                
                if sw==1
                    u =  A * sign(sin(2 * pi * (t - t0) / T));       
                    v = A * sign(sin(2 * pi * (n - t0) / N));
                end
                if sw==0
                    pulse_width = 1;
                    u = ((abs(t)) <= pulse_width/2)*A;
                    v = zeros(1,N);
                    v (n == 0) = 1*A;
                end
            elseif value == "Rampa"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                A=app.AmplitudSlider.Value;                     % Amplitud
                sw=app.PeriodicidadCheckBox.Value;
                T = app.PulsoSlider.Value;                      % Longitud de Pulso
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo

                u = A * ((t - t0) .* (t >= t0));
                v = A * ((n - t0) .* (n >= t0));
                if sw==1
                    u=u - floor(u / T) * T;
                    v = mod(v, T);
                end
            elseif value == "Pulso Triangular"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                sw=app.PeriodicidadCheckBox.Value;   
                A = app.AmplitudSlider.Value;                   % Amplitud
                T = app.PulsoSlider.Value;                      % Longitud de Pulso
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo
                
                u = A * (1 - abs((t - t0) / (T / 2))) .* ((abs(t - t0) <= T / 2));
                v = zeros(1, N);
                v(n == 0 -t0) = 1;
                v(n == 1 -t0) = 0.5;
                v(n == -1 -t0) = 0.5;
                if sw==1
                    % Calcular la mitad del período
                    mitad_periodo = T / 2;
        
                    % Inicializar la señal de onda triangular
                    u = zeros(size(t));
        
                     % Generar la señal de onda triangular
                     for i = 1:length(t)
                        % Calcular el valor de la señal en el tiempo actual
                        tiempo_actual = mod(t(i), T);
                        if tiempo_actual < mitad_periodo
                            u(i) = (A / mitad_periodo) * tiempo_actual;
                        else
                            u(i) = A - (A / mitad_periodo) * (tiempo_actual - mitad_periodo);
                        end
                     end
    
                    % Calcular la mitad del período
                    mitad_periodo = T / 2;
        
                    % Inicializar la señal de onda triangular discreta
                    v = zeros(size(n));
        
                    % Generar la señal de onda triangular discreta
                    for i = 1:length(n)
                        % Calcular el valor de la señal en el índice actual
                        tiempo_actual = mod(n(i), T);
                        if tiempo_actual < mitad_periodo
                            v(i) = (A / mitad_periodo) * tiempo_actual;
                        else
                            v(i) = A - (A / mitad_periodo) * (tiempo_actual - mitad_periodo);
                        end
                    end
                end
            elseif value == "Exponencial"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                A = app.AmplitudSlider.Value;                   % Amplitud de Señal
                a = app.AlphaSlider.Value;                      % Alpha
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo

                u =  A * (a).^ (t - t0);
                v = A * (a).^ (n - t0);
            elseif value == "Senoidal"
                fs = 1000;                                      % Crear un vector de tiempo
                f = app.FrecuenciaSlider.Value;                 % Frecuencia    
                fin=app.VectorTiempoSlider.Value;     
                ini=-fin;
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                A = app.AmplitudSlider.Value;                   % Amplitud de Señal
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo

                sw=app.PeriodicidadCheckBox.Value;

                if sw==1
                    u =  A * sin(2 * pi * f * (t - t0));  
                    v =  A * sin(2 * pi * 0.1 * (n - t0));
                end
                if sw==0
                    t=-0.5:0.01:0.5;
                    u =  A * sin(2 * pi * f * t);
                    n=-5:1:5;
                    v =  A * sin(2 * pi * 0.1 * n);
                end
            elseif value == "Triangular"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;       
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                A = app.AmplitudSlider.Value;                   % Amplitud

                sw=app.PeriodicidadCheckBox.Value;
                
                T = app.PulsoSlider.Value;                      % Período
                M = 5;                                          % Numero de muestras x ciclo
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo
    
                if sw==1
                    u = A * ((t) - T * floor((t -t0) / T));
                    v = A * ((n) - M * floor((n -t0) / M));
                end
                if sw==0
                    t=0:0.01:T;
                    u = A * ((t) - T * floor((t) / T));
                    n=0:1:M;
                    v = A * ((n) - M * floor((n ) / M));
                end
            elseif value == "Cuadrada"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                T=app.PulsoSlider.Value;
                f = app.FrecuenciaSlider.Value;                 % Frecuencia
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de Muestras
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                A = app.AmplitudSlider.Value;                   % Amplitud
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo

                sw=app.PeriodicidadCheckBox.Value;

                if sw==1
                    u =  A * sign(sin(2 * pi * f * (t - t0)));
                    v = A * sign(sin(2 * pi * f * (n - t0)));
                end
                if sw==0
                    t=0:0.01:T;
                    u =  A * sign(sin(2 * pi * t / T));
                    n=0:1:N;
                    v = A * sign(sin(2 * pi * n / N));
                end
            end
            plot(app.UIAxes,t, u);
            title(app.UIAxes,'x1(t)');
            xlabel(app.UIAxes,'Tiempo');
            ylabel(app.UIAxes,'Amplitud');
           
            app.senal1 = u;
            app.senal3 = v;
            app.tiempo = t;
            app.muestreo = n;
            app.ks = fin;
            app.ki = ini;
    
            %stem(app.UIAxes2,n, v);
            %title(app.UIAxes2,'x[n]');
            %xlabel(app.UIAxes2,'Muestras');
            %ylabel(app.UIAxes2,'Amplitud');
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;

            if value == "Escalon"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                a=app.AmplitudSlider.Value;
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de tiempo Discreto
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo
          
                u = zeros(size(t));
                u(t >= 0-t0) = a*1;
                v = zeros(1,N);
                v (n >= 0-t0) = a*1;
                
            elseif value == "Pulso"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                A=app.AmplitudSlider.Value;                     % Amplitud
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de tiempo Discreto
                sw=app.PeriodicidadCheckBox.Value;
                T = app.PulsoSlider.Value;                      % Longitud de Pulso
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo
                
                if sw==1
                    u =  A * sign(sin(2 * pi * (t - t0) / T));       
                    v = A * sign(sin(2 * pi * (n - t0) / N));
                end
                if sw==0
                    pulse_width = 1;
                    u = ((abs(t)) <= pulse_width/2)*A;
                    v = zeros(1,N);
                    v (n == 0) = 1*A;
                end
            elseif value == "Rampa"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                A=app.AmplitudSlider.Value;                     % Amplitud
                sw=app.PeriodicidadCheckBox.Value;
                T = app.PulsoSlider.Value;                      % Longitud de Pulso
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo

                u = A * ((t - t0) .* (t >= t0));
                v = A * ((n - t0) .* (n >= t0));
                if sw==1
                    u=u - floor(u / T) * T;
                    v = mod(v, T);
                end
            elseif value == "Pulso Triangular"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                sw=app.PeriodicidadCheckBox.Value;   
                A = app.AmplitudSlider.Value;                   % Amplitud
                T = app.PulsoSlider.Value;                      % Longitud de Pulso
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo
                
                u = A * (1 - abs((t - t0) / (T / 2))) .* ((abs(t - t0) <= T / 2));
                v = zeros(1, N);
                v(n == 0 -t0) = 1;
                v(n == 1 -t0) = 0.5;
                v(n == -1 -t0) = 0.5;
                if sw==1
                    % Calcular la mitad del período
                    mitad_periodo = T / 2;
        
                    % Inicializar la señal de onda triangular
                    u = zeros(size(t));
        
                     % Generar la señal de onda triangular
                     for i = 1:length(t)
                        % Calcular el valor de la señal en el tiempo actual
                        tiempo_actual = mod(t(i), T);
                        if tiempo_actual < mitad_periodo
                            u(i) = (A / mitad_periodo) * tiempo_actual;
                        else
                            u(i) = A - (A / mitad_periodo) * (tiempo_actual - mitad_periodo);
                        end
                     end
    
                    % Calcular la mitad del período
                    mitad_periodo = T / 2;
        
                    % Inicializar la señal de onda triangular discreta
                    v = zeros(size(n));
        
                    % Generar la señal de onda triangular discreta
                    for i = 1:length(n)
                        % Calcular el valor de la señal en el índice actual
                        tiempo_actual = mod(n(i), T);
                        if tiempo_actual < mitad_periodo
                            v(i) = (A / mitad_periodo) * tiempo_actual;
                        else
                            v(i) = A - (A / mitad_periodo) * (tiempo_actual - mitad_periodo);
                        end
                    end
                end
            elseif value == "Exponencial"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                A = app.AmplitudSlider.Value;                   % Amplitud de Señal
                a = app.AlphaSlider.Value;                      % Alpha
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo

                u =  A * (a).^ (t - t0);
                v = A * (a).^ (n - t0);
            elseif value == "Senoidal"
                fs = 1000;                                      % Crear un vector de tiempo
                f = app.FrecuenciaSlider.Value;                 % Frecuencia    
                fin=app.VectorTiempoSlider.Value;     
                ini=-fin;
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                A = app.AmplitudSlider.Value;                   % Amplitud de Señal
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo

                sw=app.PeriodicidadCheckBox.Value;

                if sw==1
                    u =  A * sin(2 * pi * f * (t - t0));  
                    v =  A * sin(2 * pi * 0.1 * (n - t0));
                end
                if sw==0
                    t=-0.5:0.01:0.5;
                    u =  A * sin(2 * pi * f * t);
                    n=-5:1:5;
                    v =  A * sin(2 * pi * 0.1 * n);
                end
            elseif value == "Triangular"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;       
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de muestas
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                A = app.AmplitudSlider.Value;                   % Amplitud

                sw=app.PeriodicidadCheckBox.Value;
                
                T = app.PulsoSlider.Value;                      % Período
                M = 5;                                          % Numero de muestras x ciclo
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo
    
                if sw==1
                    u = A * ((t) - T * floor((t -t0) / T));
                    v = A * ((n) - M * floor((n -t0) / M));
                end
                if sw==0
                    t=0:0.01:T;
                    u = A * ((t) - T * floor((t) / T));
                    n=0:1:M;
                    v = A * ((n) - M * floor((n ) / M));
                end
            elseif value == "Cuadrada"
                fs = 1000;                                      % Crear un vector de tiempo
                fin=app.VectorTiempoSlider.Value;
                ini=-fin;
                T=app.PulsoSlider.Value;
                f = app.FrecuenciaSlider.Value;                 % Frecuencia
                t = ini:1/fs:fin;                               % Rango de tiempo Continuo
                N = fin-ini+1;                                  % Numero de Muestras
                n = ini:1:(ini + N -1);                         % Rango de Tiempo Discreto
                A = app.AmplitudSlider.Value;                   % Amplitud
                t0 = app.DesplazamientoSlider.Value;            % Desplazamiento en el tiempo

                sw=app.PeriodicidadCheckBox.Value;

                if sw==1
                    u =  A * sign(sin(2 * pi * f * (t - t0)));
                    v = A * sign(sin(2 * pi * f * (n - t0)));
                end
                if sw==0
                    t=0:0.01:T;
                    u =  A * sign(sin(2 * pi * t / T));
                    n=0:1:N;
                    v = A * sign(sin(2 * pi * n / N));
                end
            end
            plot(app.UIAxes2,t, u);
            title(app.UIAxes2,'x2(t)');
            xlabel(app.UIAxes2,'Tiempo');
            ylabel(app.UIAxes2,'Amplitud');

            app.senal2 = u;
            app.senal4 = v;
            app.tiempo = t;
            app.muestreo = n;
            app.ls = fin;
            app.li = ini;

            %stem(app.UIAxes2,n, v);
            %title(app.UIAxes2,'x[n]');
            %xlabel(app.UIAxes2,'Muestras');
            %ylabel(app.UIAxes2,'Amplitud');
        end

        % Button pushed function: SumaButton
        function SumaButtonPushed(app, event)
            yt = app.senal1 + app.senal2;
            zt = app.senal3 + app.senal4;

            plot(app.UIAxes3,app.tiempo, yt);
            title(app.UIAxes3,'y(t)');
            xlabel(app.UIAxes3,'Tiempo');
            ylabel(app.UIAxes3,'Amplitud');

            stem(app.UIAxes4,app.muestreo, zt);
            title(app.UIAxes4,'y[n]');
            xlabel(app.UIAxes4,'Tiempo');
            ylabel(app.UIAxes4,'Amplitud');
        end

        % Button pushed function: MultiplicacinButton
        function MultiplicacinButtonPushed(app, event)
            yt = app.senal1 .* app.senal2;
            zt = app.senal3 .* app.senal4;

            plot(app.UIAxes3,app.tiempo, yt);
            title(app.UIAxes3,'y(t)');
            xlabel(app.UIAxes3,'Tiempo');
            ylabel(app.UIAxes3,'Amplitud');

            stem(app.UIAxes4,app.muestreo, zt);
            title(app.UIAxes4,'y[n]');
            xlabel(app.UIAxes4,'Tiempo');
            ylabel(app.UIAxes4,'Amplitud');
        end

        % Button pushed function: DiferenciacinButton
        function DiferenciacinButtonPushed(app, event)
            yt = diff(app.senal1);
            zt = diff(app.senal3);

            plot(app.UIAxes3,app.tiempo(1:end-1), yt);
            title(app.UIAxes3,'y(t)');
            xlabel(app.UIAxes3,'Tiempo');
            ylabel(app.UIAxes3,'Amplitud');

            stem(app.UIAxes4,app.muestreo(1:end-1), zt);
            title(app.UIAxes4,'y[n]');
            xlabel(app.UIAxes4,'Tiempo');
            ylabel(app.UIAxes4,'Amplitud');
        end

        % Button pushed function: IntegracinButton
        function IntegracinButtonPushed(app, event)
            yt = cumsum(app.senal1);
            zt = cumsum(app.senal3);

            plot(app.UIAxes3,app.tiempo, yt);
            title(app.UIAxes3,'y(t)');
            xlabel(app.UIAxes3,'Tiempo');
            ylabel(app.UIAxes3,'Amplitud');

            stem(app.UIAxes4,app.muestreo, zt);
            title(app.UIAxes4,'y(t)');
            xlabel(app.UIAxes4,'Tiempo');
            ylabel(app.UIAxes4,'Amplitud');
        end

        % Button pushed function: ConvolucinButton
        function ConvolucinButtonPushed(app, event)
            yt = conv(app.senal1,app.senal2,'same');
            t_conv = 0:0.01:(length(yt)-1)*0.01;
            plot(app.UIAxes3,t_conv, yt);
            title(app.UIAxes3,'y(t)');
            xlabel(app.UIAxes3,'Tiempo');
            ylabel(app.UIAxes3,'Amplitud');
        end

        % Button pushed function: AlgoritmoConvolucionButton
        function AlgoritmoConvolucionButtonPushed(app, event)
            % Definir la señal 1
            xt = app.senal1;
            t = app.tiempo;

            % Encuentra la posición del primer tiempo no negativo
            indice_tiempo_no_negativo = find(t >= 0, 1);
        
            % Longitud desde el tiempo 0 hasta el primer tiempo no negativo
            longitud_desde_tiempo_0 = length(t) - indice_tiempo_no_negativo + 1

            % Definir la señal 2
            ht = app.senal2;
                    
            % Indices de las señales
            kf = app.ks;     %Se obtiene valor del Slider de la App
            lf = app.ls;     %Se obtiene valor del Slider de la App
            
            Nmin = app.ki + app.li;
            Nmax = kf + lf;
            

            limite = ceil(length(xt)/2)

            % Inicializar el vector de Convolucion
            yt = [];
            
            % Invertimos la señal ht
            h_t = ht(end:-1:1);
            
            for i = -limite:limite
                % Desplazar el Vector N
                indice_h = i; 
                indice_x = 1;
                
                if indice_h < 0
                    indice_a = -1 * indice_h;
                    h_1 = [zeros(1, indice_a),h_t];
                elseif indice_h >= 0
                    h_1 = h_t;
                end
                
                if indice_x < indice_h
                    indice_aux = indice_h - indice_x;
                    new_x = [zeros(1, indice_aux),xt];
                    new_size = length(new_x) - length(h_1);
                    new_h = [h_1,zeros(1, new_size)];
                elseif indice_x > indice_h
                    indice_aux = (indice_h)*-1;
                    new_x = [xt,zeros(1, indice_aux)];
                    new_h = h_1;
                end
                
                if indice_h ~= 0
                    aux = new_h .* new_x;
                end
            
                yt = [sum(aux),yt];
            
            end
            %lenght(xt)


            t = 0:length(xt);
            plot(app.UIAxes4,yt);
            title(app.UIAxes4,'y(t)');
            xlabel(app.UIAxes4,'Tiempo');
            ylabel(app.UIAxes4,'Amplitud');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.PeriodicidadCheckBox = uicheckbox(app.UIFigure);
            app.PeriodicidadCheckBox.Text = 'Periodicidad';
            app.PeriodicidadCheckBox.Value = true;
            app.Senales = uilistbox(app.UIFigure);
            app.Senales.Items = {'Escalon', 'Pulso', 'Rampa', 'Pulso Triangular', 'Exponencial', 'Senoidal', 'Triangular', 'Cuadrada'};
            app.Senales.ValueChangedFcn = createCallbackFcn(app, @SenalesValueChanged, true);
            app.Senales.Value = 'Escalon';
            app.GraficasLabel = uilabel(app.UIFigure);
            app.GraficasLabel.Text = 'Graficas';
            app.SealesLabel = uilabel(app.UIFigure);
            app.SealesLabel.Text = 'Señales';
            app.AmplitudSliderLabel = uilabel(app.UIFigure);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.AmplitudSlider = uislider(app.UIFigure);
            app.AmplitudSlider.Limits = [1 5];
            app.AmplitudSlider.MajorTicks = [1 2 3 4 5];
            app.AmplitudSlider.Value = 1;
            app.FrecuenciaSliderLabel = uilabel(app.UIFigure);
            app.FrecuenciaSliderLabel.Text = 'Frecuencia';
            app.FrecuenciaSlider = uislider(app.UIFigure);
            app.FrecuenciaSlider.Limits = [0 1];
            app.Practica4Label = uilabel(app.UIFigure);
            app.Practica4Label.Text = 'Practica 4';
            app.VectorTiempoSliderLabel = uilabel(app.UIFigure);
            app.VectorTiempoSliderLabel.Text = {'Vector'; 'Tiempo'};
            app.VectorTiempoSlider = uislider(app.UIFigure);
            app.VectorTiempoSlider.Limits = [1 5];
            app.VectorTiempoSlider.MajorTicks = [1 2 3 4 5];
            app.VectorTiempoSlider.Value = 1;
            app.PulsoSliderLabel = uilabel(app.UIFigure);
            app.PulsoSliderLabel.Text = 'Pulso';
            app.PulsoSlider = uislider(app.UIFigure);
            app.PulsoSlider.Limits = [1 5];
            app.PulsoSlider.MajorTicks = [1 2 3 4 5];
            app.PulsoSlider.Value = 1;
            app.AlphaSliderLabel = uilabel(app.UIFigure);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.UIFigure);
            app.AlphaSlider.Limits = [-2 2];
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Escalon', 'Pulso', 'Rampa', 'Pulso Triangular', 'Exponencial', 'Senoidal', 'Triangular', 'Cuadrada'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Escalon';
            app.SumaButton = uibutton(app.UIFigure, 'push');
            app.SumaButton.ButtonPushedFcn = createCallbackFcn(app, @SumaButtonPushed, true);
            app.SumaButton.Enable = 'off';
            app.SumaButton.Visible = 'off';
            app.SumaButton.Text = 'Suma';
            app.MultiplicacinButton = uibutton(app.UIFigure, 'push');
            app.MultiplicacinButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicacinButtonPushed, true);
            app.MultiplicacinButton.Enable = 'off';
            app.MultiplicacinButton.Visible = 'off';
            app.MultiplicacinButton.Text = 'Multiplicación';
            app.DiferenciacinButton = uibutton(app.UIFigure, 'push');
            app.DiferenciacinButton.ButtonPushedFcn = createCallbackFcn(app, @DiferenciacinButtonPushed, true);
            app.DiferenciacinButton.Enable = 'off';
            app.DiferenciacinButton.Visible = 'off';
            app.DiferenciacinButton.Text = 'Diferenciación';
            app.SealContinuaLabel = uilabel(app.UIFigure);
            app.SealContinuaLabel.Text = 'Señal Continua';
            app.SealDiscretaLabel = uilabel(app.UIFigure);
            app.SealDiscretaLabel.Text = 'Señal Discreta';
            app.IntegracinButton = uibutton(app.UIFigure, 'push');
            app.IntegracinButton.ButtonPushedFcn = createCallbackFcn(app, @IntegracinButtonPushed, true);
            app.IntegracinButton.Enable = 'off';
            app.IntegracinButton.Visible = 'off';
            app.IntegracinButton.Text = 'Integración';
            app.DesplazamientoSliderLabel = uilabel(app.UIFigure);
            app.DesplazamientoSliderLabel.Text = 'Desplazamiento';
            app.DesplazamientoSlider = uislider(app.UIFigure);
            app.DesplazamientoSlider.Limits = [-3 3];
            app.EscalamientodeTiempoSliderLabel = uilabel(app.UIFigure);
            app.EscalamientodeTiempoSliderLabel.Text = {'Escalamiento'; 'de Tiempo'};
            app.EscalamientodeTiempoSlider = uislider(app.UIFigure);
            app.EscalamientodeTiempoSlider.Limits = [-3 3];
            app.EscalamientodeTiempoSlider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.EscalamientodeTiempoSlider.MajorTickLabels = {'-3', '-2', '-1', '0', '1', '2', '3'};
            app.EscalamientodeTiempoSlider.Value = 1;
            app.ConvolucinButton = uibutton(app.UIFigure, 'push');
            app.ConvolucinButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolucinButtonPushed, true);
            app.ConvolucinButton.Text = 'Convolución';
            app.AlgoritmoConvolucionButton = uibutton(app.UIFigure, 'push');
            app.AlgoritmoConvolucionButton.ButtonPushedFcn = createCallbackFcn(app, @AlgoritmoConvolucionButtonPushed, true);
            app.AlgoritmoConvolucionButton.Text = 'Algoritmo Convolucion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PracticaConvolu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end